classdef AOGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadCondifurationsMenu          matlab.ui.container.Menu
        SaveConfigurationsMenu          matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        DeactivatePeripheralsMenu       matlab.ui.container.Menu
        ExtraFiguresMenu                matlab.ui.container.Menu
        PMTSaturationInspector          matlab.ui.container.Menu
        ScanAssistantMenu               matlab.ui.container.Menu
        FindMyinstrumentPortMenu        matlab.ui.container.Menu
        InstrumentsMenu                 matlab.ui.container.Menu
        StagesMenu                      matlab.ui.container.Menu
        ScanningAssistantMenu           matlab.ui.container.Menu
        AFGMenu                         matlab.ui.container.Menu
        OscilloscopeMenu                matlab.ui.container.Menu
        ResetInstrumentsCommunicationMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        AcoustoOpticSystemLabel         matlab.ui.control.Label
        ScanPlotTab                     matlab.ui.container.TabGroup
        MainAxisTab                     matlab.ui.container.Tab
        axMainAxis                      matlab.ui.control.UIAxes
        MainPlaneTab                    matlab.ui.container.Tab
        axMainPlane                     matlab.ui.control.UIAxes
        NavigatorTab                    matlab.ui.container.Tab
        axNav                           matlab.ui.control.UIAxes
        MainAxisAveragedTab             matlab.ui.container.Tab
        axMainAxisAvg                   matlab.ui.control.UIAxes
        MainPlaneAveragedTab            matlab.ui.container.Tab
        axMainPlaneAvg                  matlab.ui.control.UIAxes
        NavigatorAveragedTab            matlab.ui.container.Tab
        axNavAvg                        matlab.ui.control.UIAxes
        SimulationNamePanel             matlab.ui.container.Panel
        MeasurementNameEditFieldLabel   matlab.ui.control.Label
        MeasurementNameEditField        matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        SaveResultsandVarsCheckBox      matlab.ui.control.CheckBox
        SaveFigsCheckBox                matlab.ui.control.CheckBox
        RawDataCheckBox                 matlab.ui.control.CheckBox
        NetSignalCheckBox               matlab.ui.control.CheckBox
        DeMultiplexedCheckBox           matlab.ui.control.CheckBox
        ReshapedCheckBox_2              matlab.ui.control.CheckBox
        FFTCheckBox_2                   matlab.ui.control.CheckBox
        SaveSpecificDataLabel           matlab.ui.control.Label
        PhiChCmplxCheckBox              matlab.ui.control.CheckBox
        Button                          matlab.ui.control.Button
        MeasMethod                      matlab.ui.container.TabGroup
        SingleMeasurementTab            matlab.ui.container.Tab
        RunButtonAO                     matlab.ui.control.Button
        RunLiveButton                   matlab.ui.control.Button
        StagesPanel_3                   matlab.ui.container.Panel
        mmLabel_17                      matlab.ui.control.Label
        mmLabel_18                      matlab.ui.control.Label
        XpositionEditField_2Label       matlab.ui.control.Label
        XpositionEditField_2            matlab.ui.control.NumericEditField
        YpositionEditField_2Label       matlab.ui.control.Label
        YpositionEditField_2            matlab.ui.control.NumericEditField
        MeasureatcurrentposCheckBox     matlab.ui.control.CheckBox
        LiveAcoustoOpticsPanel          matlab.ui.container.Panel
        LimitLiveAOCheckBox             matlab.ui.control.CheckBox
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        ImportsavedResultsPanel         matlab.ui.container.Panel
        DataEditFieldLabel              matlab.ui.control.Label
        DataEditField                   matlab.ui.control.EditField
        analysisDataPathButton          matlab.ui.control.Button
        AnalyzeOrigButton               matlab.ui.control.Button
        VarsEditFieldLabel              matlab.ui.control.Label
        VarsEditField                   matlab.ui.control.EditField
        analysisVarsPathButton          matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        AnalyizeNewButton               matlab.ui.control.Button
        UseVirtualDataCheckBox          matlab.ui.control.CheckBox
        ImportVarsButton                matlab.ui.control.Button
        ExportVarsButton                matlab.ui.control.Button
        Scan3DTab                       matlab.ui.container.Tab
        StagesPanel                     matlab.ui.container.Panel
        StagesCalibToolButton           matlab.ui.control.Button
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_7                       matlab.ui.control.Label
        mmLabel_8                       matlab.ui.control.Label
        mmLabel_9                       matlab.ui.control.Label
        StartEditFieldLabel             matlab.ui.control.Label
        S2DFirstStartEditField          matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        S2DFirstEndEditField            matlab.ui.control.NumericEditField
        StartEditField_2Label           matlab.ui.control.Label
        S2DSecondStartEditField         matlab.ui.control.NumericEditField
        EndEditField_2Label             matlab.ui.control.Label
        S2DSecondEndEditField           matlab.ui.control.NumericEditField
        StrideEditFieldLabel            matlab.ui.control.Label
        S2DFirstStrideEditField         matlab.ui.control.NumericEditField
        StrideEditField_2Label          matlab.ui.control.Label
        S2DSecondStrideEditField        matlab.ui.control.NumericEditField
        AxisDropDownLabel               matlab.ui.control.Label
        S2DFirstAxisDropDown            matlab.ui.control.DropDown
        S2DImportLimitsfromfileButton   matlab.ui.control.Button
        FirstScanAxisLabel              matlab.ui.control.Label
        SecondScanAxisLabel             matlab.ui.control.Label
        AxisDropDown_2Label             matlab.ui.control.Label
        S2DSecondAxisDropDown           matlab.ui.control.DropDown
        Perform2DscanCheckBox           matlab.ui.control.CheckBox
        S2DRunButton                    matlab.ui.control.Button
        RepeatsPanel                    matlab.ui.container.Panel
        RepeatsEditFieldLabel           matlab.ui.control.Label
        S2DRepeatsEditField             matlab.ui.control.NumericEditField
        S2DImportResultsButton          matlab.ui.control.Button
        S2DExportScanVarsButton         matlab.ui.control.Button
        S2DImportScanVarsButton         matlab.ui.control.Button
        ConsistencyTab                  matlab.ui.container.Tab
        RunButtonConsistency            matlab.ui.control.Button
        StagesPanel_2                   matlab.ui.container.Panel
        mmLabel_10                      matlab.ui.control.Label
        mmLabel_11                      matlab.ui.control.Label
        XpositionEditFieldLabel         matlab.ui.control.Label
        XpositionEditField              matlab.ui.control.NumericEditField
        YpositionEditFieldLabel         matlab.ui.control.Label
        YpositionEditField              matlab.ui.control.NumericEditField
        TimeFramePropertiesPanel        matlab.ui.container.Panel
        sLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        MaxTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MaxTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        MinTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MinTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        TimeStrideEditFieldLabel        matlab.ui.control.Label
        TimeStrideEditField             matlab.ui.control.NumericEditField
        SetsperTimeFrameEditFieldLabel  matlab.ui.control.Label
        SetsperTimeFrameEditField       matlab.ui.control.NumericEditField
        ImportResultsButton_2           matlab.ui.control.Button
        ExportScanVarsButton_2          matlab.ui.control.Button
        ImportScanVarsButton_2          matlab.ui.control.Button
        FiguresPanel_2                  matlab.ui.container.Panel
        FrameToPlotSpinnerLabel         matlab.ui.control.Label
        FrameToPlotSpinner              matlab.ui.control.Spinner
        SettoplotSpinnerLabel           matlab.ui.control.Label
        SettoplotSpinner                matlab.ui.control.Spinner
        ZCoorSpinnerLabel               matlab.ui.control.Label
        ZCoorConsSpinner                matlab.ui.control.Spinner
        sLabel_6                        matlab.ui.control.Label
        mmLabel_15                      matlab.ui.control.Label
        ConsistencyPlotTab              matlab.ui.container.TabGroup
        AllFramesStatisticsTab          matlab.ui.container.Tab
        axAllFrames                     matlab.ui.control.UIAxes
        CurrentFrameTab                 matlab.ui.container.Tab
        axCurFrame                      matlab.ui.control.UIAxes
        CurrentSetQuantsTab             matlab.ui.container.Tab
        axCurSet                        matlab.ui.control.UIAxes
        FramesNavigatorTab              matlab.ui.container.Tab
        axNavFrame                      matlab.ui.control.UIAxes
        SetsNavigatorQuantsTab          matlab.ui.container.Tab
        axNavSet                        matlab.ui.control.UIAxes
        GlobalParameters                matlab.ui.container.TabGroup
        SignalExcitationTab             matlab.ui.container.Tab
        UseQuantCheckBox                matlab.ui.control.CheckBox
        SamplingFrequencyextClkPanel    matlab.ui.container.Panel
        MHzLabel_2                      matlab.ui.control.Label
        VLabel_10                       matlab.ui.control.Label
        VLabel_11                       matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        AmplitudeEditField_4Label       matlab.ui.control.Label
        AmplitudeEditField_4            matlab.ui.control.EditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.EditField
        DutyCycleEditFieldLabel         matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.NumericEditField
        UltrasoundSignalPropertiesPanel  matlab.ui.container.Panel
        MHzLabel                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        KHzLabel_3                      matlab.ui.control.Label
        SineFrequencyEditFieldLabel     matlab.ui.control.Label
        SineFrequencyEditField          matlab.ui.control.NumericEditField
        TrainFrequencyEditFieldLabel    matlab.ui.control.Label
        TrainFrequencyEditField         matlab.ui.control.NumericEditField
        SinePeriodsEditFieldLabel       matlab.ui.control.Label
        SinePeriodsEditField            matlab.ui.control.NumericEditField
        OptimizetrainfrequencyCheckBox  matlab.ui.control.CheckBox
        GeometryPanel                   matlab.ui.container.Panel
        PhantomdepthEditFieldLabel      matlab.ui.control.Label
        PhantomdepthEditField           matlab.ui.control.NumericEditField
        TransducerheightEditFieldLabel  matlab.ui.control.Label
        TransducerheightEditField       matlab.ui.control.NumericEditField
        TransducerdistanceLabel         matlab.ui.control.Label
        TransducerdistanceEditField     matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        SpeedinMediumEditFieldLabel     matlab.ui.control.Label
        SpeedinMediumEditField          matlab.ui.control.NumericEditField
        cmLabel_3                       matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        TimetosampleEditFieldLabel      matlab.ui.control.Label
        TimetosampleEditField           matlab.ui.control.NumericEditField
        UseTimeQuantCheckBox            matlab.ui.control.CheckBox
        QuantTimeEditFieldLabel         matlab.ui.control.Label
        QuantTimeEditField              matlab.ui.control.NumericEditField
        FastAnalysisCheckBox            matlab.ui.control.CheckBox
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        CalcOnlyUSFrequencyLabel        matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        secLabel_2                      matlab.ui.control.Label
        UseHadamardCodeCheckBox         matlab.ui.control.CheckBox
        PeripheralsTab                  matlab.ui.container.Tab
        FunctionGeneratorPanel          matlab.ui.container.Panel
        SclkEditFieldLabel              matlab.ui.control.Label
        SclkEditField                   matlab.ui.control.NumericEditField
        DAQFilterDropDownLabel          matlab.ui.control.Label
        DAQFilterDropDown               matlab.ui.control.DropDown
        TriggerWidthEditFieldLabel      matlab.ui.control.Label
        TriggerWidthEditField           matlab.ui.control.NumericEditField
        AmplitudeEditField_3Label       matlab.ui.control.Label
        AmplitudeEditField_3            matlab.ui.control.NumericEditField
        BiasEditFieldLabel              matlab.ui.control.Label
        BiasEditField                   matlab.ui.control.NumericEditField
        MSsLabel                        matlab.ui.control.Label
        SLabel                          matlab.ui.control.Label
        VLabel_8                        matlab.ui.control.Label
        VLabel_9                        matlab.ui.control.Label
        DigitizerPanel                  matlab.ui.container.Panel
        Label_10                        matlab.ui.control.Label
        NumberOfChannelsEditFieldLabel  matlab.ui.control.Label
        NumberOfChannelsEditField       matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        PeriodtoSampleEditFieldLabel    matlab.ui.control.Label
        PeriodtoSampleEditField         matlab.ui.control.NumericEditField
        VppLabel                        matlab.ui.control.Label
        VoltsRangeEditFieldLabel        matlab.ui.control.Label
        VoltsRangeEditField             matlab.ui.control.NumericEditField
        SamplingMethodDropDownLabel     matlab.ui.control.Label
        SamplingMethodDropDown          matlab.ui.control.DropDown
        LaserPanel                      matlab.ui.container.Panel
        nmLabel                         matlab.ui.control.Label
        mWLabel                         matlab.ui.control.Label
        KHzLabel_4                      matlab.ui.control.Label
        sLabel_5                        matlab.ui.control.Label
        WavelengthEditFieldLabel        matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel         matlab.ui.control.Label
        LineWidthEditField              matlab.ui.control.NumericEditField
        SpeckleDurationEditFieldLabel   matlab.ui.control.Label
        SpeckleDurationEditField        matlab.ui.control.NumericEditField
        TurnOffAFGButton                matlab.ui.control.Button
        CloseIOPortButton               matlab.ui.control.Button
        StagesHomeButton                matlab.ui.control.Button
        StagesLayoutInSystemPanel       matlab.ui.container.Panel
        DepthAxisDropDownLabel          matlab.ui.control.Label
        DepthAxisDropDown               matlab.ui.control.DropDown
        stage1Label                     matlab.ui.control.Label
        stage2Label                     matlab.ui.control.Label
        stageIDDropDownLabel            matlab.ui.control.Label
        stage1IDDropDown                matlab.ui.control.DropDown
        stageIDDropDown_2Label          matlab.ui.control.Label
        stage2IDDropDown                matlab.ui.control.DropDown
        SetStagesGeometryButton         matlab.ui.control.Button
        TestAssignmentButton            matlab.ui.control.Button
        TestLableLabel                  matlab.ui.control.Label
        ProblemVarsTab                  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        SystemClkTab                    matlab.ui.container.Tab
        UITable_6                       matlab.ui.control.Table
        USSignalTab                     matlab.ui.container.Tab
        UITable_5                       matlab.ui.control.Table
        SamplesTab                      matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        TimingTab                       matlab.ui.container.Tab
        UITable_3                       matlab.ui.control.Table
        DigitizerTab_2                  matlab.ui.container.Tab
        UITable_4                       matlab.ui.control.Table
        LengthTab                       matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        FreqTab                         matlab.ui.container.Tab
        UITable_7                       matlab.ui.control.Table
        PlottingTab                     matlab.ui.container.Tab
        AcoustoOpticsPanel              matlab.ui.container.Panel
        PlotChannelSpinnerLabel         matlab.ui.control.Label
        PlotChannelSpinner              matlab.ui.control.Spinner
        ZCoorSpinner_3Label             matlab.ui.control.Label
        AOZIdxSpinner                   matlab.ui.control.Spinner
        QuantSpinnerLabel               matlab.ui.control.Label
        QuantAOSpinner                  matlab.ui.control.Spinner
        ExternalClkCheckBox             matlab.ui.control.CheckBox
        UltrasoundSignalCheckBox        matlab.ui.control.CheckBox
        FullSignalCheckBox              matlab.ui.control.CheckBox
        MeasuredSamplesCheckBox         matlab.ui.control.CheckBox
        NetSamplesCheckBox              matlab.ui.control.CheckBox
        ReshapedCheckBox_3              matlab.ui.control.CheckBox
        FFTCheckBox_3                   matlab.ui.control.CheckBox
        PhiChCheckBox                   matlab.ui.control.CheckBox
        PhiCheckBox                     matlab.ui.control.CheckBox
        PhiQuantCheckBox                matlab.ui.control.CheckBox
        DemultiplexedCheckBox           matlab.ui.control.CheckBox
        FreqEnviromentEditFieldLabel    matlab.ui.control.Label
        FreqEnviromentEditField         matlab.ui.control.NumericEditField
        KHzLabel_5                      matlab.ui.control.Label
        mmLabel_16                      matlab.ui.control.Label
        dispFullFFTCheckBox             matlab.ui.control.CheckBox
        QuantAvgFFTCheckBox             matlab.ui.control.CheckBox
        StopButton                      matlab.ui.control.Button
        PopoutFiguresPanel              matlab.ui.container.Panel
        PopButton                       matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        ExternalFiguresCheckBox         matlab.ui.control.CheckBox
        TimeTablePanel                  matlab.ui.container.Panel
        DisplayTimeTableCheckBox        matlab.ui.control.CheckBox
        Scan2DFiguresPanel              matlab.ui.container.Panel
        NavPlainDropDownLabel           matlab.ui.control.Label
        S2dNavPlainDropDown             matlab.ui.control.DropDown
        ReapeatSpinnerLabel             matlab.ui.control.Label
        S2dRepeatSpinner                matlab.ui.control.Spinner
        DepthCoorMainAxisLabel_2        matlab.ui.control.Label
        S2dCurDepthCoorSpinner          matlab.ui.control.Spinner
        NavIdxSpinnerLabel              matlab.ui.control.Label
        S2dNavIdxSpinner                matlab.ui.control.Spinner
        mmLabel_14                      matlab.ui.control.Label
        mmLabel_19                      matlab.ui.control.Label
        NormalizeColorstoDisplayedPlaneCheckBox  matlab.ui.control.CheckBox
        acoustoOpticsPlotsTab           matlab.ui.container.TabGroup
        TimeTable                       matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        UITable2_2                      matlab.ui.control.Table
        UITable2_3                      matlab.ui.control.Table
        FFTTab                          matlab.ui.container.Tab
        axFFTsm                         matlab.ui.control.UIAxes
        AvgFFTTab                       matlab.ui.container.Tab
        axQAvgFFT                       matlab.ui.control.UIAxes
        PhiTab                          matlab.ui.container.Tab
        axPhi                           matlab.ui.control.UIAxes
        PhichTab                        matlab.ui.container.Tab
        axPhiCh                         matlab.ui.control.UIAxes
        USTab                           matlab.ui.container.Tab
        axUsSignal                      matlab.ui.control.UIAxes
        ReshapedTab                     matlab.ui.container.Tab
        axReshape                       matlab.ui.control.UIAxes
        NetTab                          matlab.ui.container.Tab
        axNetSignal                     matlab.ui.control.UIAxes
        DeMulTab                        matlab.ui.container.Tab
        axDeMul                         matlab.ui.control.UIAxes
        MeasSigTab                      matlab.ui.container.Tab
        axMeasSig                       matlab.ui.control.UIAxes
        FullSigTab                      matlab.ui.container.Tab
        axFullSignal                    matlab.ui.control.UIAxes
        ExtClkTab                       matlab.ui.container.Tab
        axExtClk                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        %parameters
        importedLimits % Description

        %--------------------------
        % New Variables
        %--------------------------
        
        %objects
        algo;
        ao;
        consistency;
        s2d;
        aoStages;
        
        %Graphics Objects Handles
        aoGraphics;
        s1dGraphics;
        s2dGraphics;
        hConsGraph;

        % AO Measurement Vars
        consVars
        
        % Data vars
        algoData;
        aoData;
        s2dData;
        s1dData;
        consData;
        stagesData;

        % eventsListeners
%         consListeners;
%         s2dListeners;
%         aoListeners;
        
        %GUI control Variables
        GUIctrl;
    end
    
    events
        updateQuant
        updateSet
        updateFrame
        
        updatePhiRepEvent
        updatePhiEvent
        
        timeTable
    end
    
    methods (Access = public)
        
        function displayAOTimeTable(app, ~, ~)
%             tic;
            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',sum([timeTable.acq timeTable.analyse timeTable.moveData])}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.analyse}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
    
            app.UITable2.Data = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;  
%             toc
        end

%---------------- Consistency Functions ---------------------%

%         Consistency Listener Responses
        function updateCurrentSetPlots(app, ~, ~)
%             if app.consData.quantDataValid
%                 app.hConsGraph.dispCurrentSet(1:app.consData.numOfQuant(app.consData.curIdx(1)) , app.consData.phiQuant);
%             end
        end
    
        function updateCurrentFramePlots(app, ~, ~)
            if app.consData.setDataValid
                app.hConsGraph.dispCurrentSet(1:app.consData.numOfQuant(app.consData.curIdx(1)) , app.consData.phiQuant);
                app.hConsGraph.dispCurrentFrame(1:app.consData.numOfSets, app.consData.phiSet,  app.consData.phiSetStd);
            end
        end
        
        function updateTotalFramePlots(app, ~, ~)
            if app.consData.frameDataValid
                app.hConsGraph.dispTotalFrame( app.consData.timeFrames, app.consData.phiFrame,  app.consData.phiFrameStd);
            end
        end
        
        % Consistency Navigator Plots functions
        function updateNavFramePlots(app)
            if app.consData.frameDataValid
                idx = app.FrameToPlotSpinner.Value;
                app.hConsGraph.setNavFrame(idx)
                app.hConsGraph.dispNavFrame( 1:app.consData.numOfSets, app.consData.phiSet, app.consData.phiSetStd);
            end
        end
        
        function updateNavSetPlots(app)
            if app.consData.setDataValid
                frame = app.FrameToPlotSpinner.Value;
                idx = app.SettoplotSpinner.Value;
                app.hConsGraph.setNavSet(idx)
                app.hConsGraph.dispNavSet( 1:app.consData.numOfQuant(frame), app.consData.phiQuant);
            end
        end
    
        % Consistency Data Update functions
        function updateConsistencyPhiQuant(app, data, curIdx)
%                 app.consData.phiQuant = data;
%                 app.consData.curIdx   = curIdx;                
%                 app.consData.quantDataValid = true;
        end
    
        function updateConsistencyPhiSet(app, dataQuant, dataSet, std, curIdx)
                app.consData.phiQuant  = dataQuant;
                app.consData.phiSet    = dataSet;
                app.consData.phiSetStd = std;
                app.consData.curIdx    = curIdx;
                
                app.updateQuantSpinner(app.consData.numOfQuant(curIdx(1)))
                app.consData.setDataValid = true;
                
        end
        
        function updateConsistencyPhiFrame(app, data, std, curIdx)
                app.consData.phiFrame    = data;
                app.consData.phiFrameStd = std;
                app.consData.curIdx      = curIdx;
                
                app.consData.frameDataValid = true;
        end
    
        function updateConsChAndPos(app)
            ch    = app.PlotChannelSpinner.Value;
            zIdx  = app.ZCoorConsSpinner.Value;
            quant = app.QuantAOSpinner.Value;
            
            % Update the graphic object
            app.consistency.graphics.gReq.ch    = ch;
            app.consistency.graphics.gReq.zIdx  = zIdx;
            app.consistency.graphics.gReq.quant = quant; 
            app.hConsGraph.setChAndPos(ch, zIdx, quant);
            
            % Update Plots
%             app.updateCurrentSetPlots();
            app.updateCurrentFramePlots();
            app.updateTotalFramePlots();
            
            app.updateNavFramePlots();
            app.updateNavSetPlots();
        end

        function initConsData(app, arrays, scan, zVec)
           names = fieldnames(scan);
           for i = 1:length(names)
                app.consData.(names{i}) = scan.(names{i});
           end
           app.consData.zVec        = zVec;
           app.consData.zVecSpinner = floor(zVec*1e5)/1e2;
           app.consData.varsValid = true;
           
           app.consData.phiQuant    =  arrays.phiQuant;
           app.consData.phiSet      =  arrays.phiSets; 
           app.consData.phiSetStd   =  arrays.phiSetsStd; 
           app.consData.phiFrame    =  arrays.phiFrame;
           app.consData.phiFrameStd =  arrays.phiFrameStd;
           
           app.consData.frameDataValid = true;
           app.consData.setDataValid   = true;
%            app.consData.quantDataValid = true;
            
           app.consData.curIdx = ones(1,3);
           
           app.AOData.zVec = zVec;
           
           app.updatePlotZSpinner();
           app.updateConsChAndPos();
           app.updateFrameAndSetSpinners();
           
           app.updateAOPlotZSpinner(zVec);
%            app.updateCurrentSetPlots();
           app.updateCurrentFramePlots();
           app.updateTotalFramePlots();
           app.updateNavFramePlots();
           app.updateNavSetPlots();
        end
    
%---------------- Scan 2d Data and Plot Functions ---------------------%    
%         % Scan 2d Plots functions (Listeners)
%         function updateS2DPlots(app, ~, ~)
%             if app.s2dData.phiValid
%                 app.hS2dGraph.dispCurMainAxis(app.s2dData.phiRep, app.s2dData.phiRepStd);
%                 app.hS2dGraph.dispCurMainPlain(app.s2dData.phiRep);
%                 app.updateS2dNavigator();
%             end
%         end
%         
%         function updateS2DPlotsAVG(app, ~, ~)
%             if app.s2dData.phiRepValid
%                 app.hS2dGraph.dispCurMainAxisRep(app.s2dData.phi, app.s2dData.phiStd);
%                 app.hS2dGraph.dispCurMainPlainRep(app.s2dData.phi);
%                 app.updateS2dNavigator();
%                 
%             end
%         end
%   
%         % Scan 2D Data Update functions
%         function updatePhiRep(app, data, std, curIdx)
%             app.s2dData.phiRep      = data;
%             app.s2dData.phiRepStd   = std;
%             app.s2dData.curIdx      = curIdx;
%             app.s2dData.phiRepValid = true;
%         end
%     
%         function updatePhi(app, data, std)
%             app.s2dData.phi      = data;
%             app.s2dData.phiStd   = std;
%             app.s2dData.phiValid = true;
%         end
% 
%         function updateS2dGeneralData(app, zVec, xVec, yVec, rep, numOfQuants)
% %             % object updates these variable backwards
% %             
% %             app.s2dData.zVec = zVec;
% %             app.s2dData.xVec = xVec;
% %             app.s2dData.yVec = yVec;
% %             app.s2dData.rep  = rep;
% %             app.s2dData.numOfQuant  = numOfQuants;
% %             
% %             app.s2dData.varsValid = true;
% %             
% %             app.AOData.zVec = zVec;
% %             app.AOData.numOfQuant = numOfQuants;
% %             
% %             app.updateAOPlotZSpinner(zVec);
% %             app.updateQuantSpinner(numOfQuants)
% %             app.updatePlaneIdxSelect();
% %             app.updateMainZSpinner();
% %             app.updatePlotRepSpinner();
% %             app.updateS2dNavigator();
%         end
    end

    methods (Access = private)

        function applyImportedLimits(app)
             app.S2DFirstStartEditField.Value   = app.importedLimits.startPosX; 
             app.S2DFirstEndEditField.Value     = app.importedLimits.endPosX; 
             app.S2DSecondStartEditField.Value  = app.importedLimits.startPosY; 
             app.S2DSecondEndEditField.Value    = app.importedLimits.endPosY; 
             app.S2DFirstStrideEditField.Value  = app.importedLimits.Xstride; 
             app.S2DSecondStrideEditField.Value = app.importedLimits.Ystride; 
        end
    
        function data = extractStructDataToTable(~, curStruct)
            names= fieldnames(curStruct);
            j=1;
            num = numel(names);
            data = cell(num,3);
            for i=1:num
                %this function requires mapping toolbox
                fieldVal  = extractfield(curStruct, names{i});
                if length(fieldVal)> 1
                    continue;
                end
                data{j,1} = names{i};
                data{j,2} = fieldVal;
                data{j,3} = 'unit';
                j=j+1;
            end
        end
        
        % ------------ Acousto Optics GUI Components Update Functions ------------------%

        function updateAOChAndPos(app)
%             ch   = app.PlotChannelSpinner.Value;
%             zIdx = app.AOZIdxSpinner.Value;
%             quantIdx = app.QuantAOSpinner.Value; 
%       
%             app.hAOGraph.setChAndPos(ch, zIdx, quantIdx);
%             app.consistency.acoustoOptics.vars.uVars.figs.ch = ch;
%             app.consistency.acoustoOptics.vars.uVars.figs.zIdx = zIdx;
        end

        % ------------ Consistency GUI Components Update Functions ------------------%
        function updateConsFrameLabel(app)
            app.sLabel_6.Text = sprintf("%.2f[mm]",app.consData.timeFrames(app.FrameToPlotSpinner.Value));
        end
        
        function updateFrameAndSetSpinners(app)
%             if app.consData.varsValid
%                 app.FrameToPlotSpinner.Limits = [1,length(app.consData.timeFrames)];
%                 app.FrameToPlotSpinner.Value  = 1;
%                 app.FrameToPlotSpinner.Step   = 1;
%                 app.FrameToPlotSpinner.Enable = true;
%                 if (app.SetsperTimeFrameEditField.Value ==1)
%                     app.SettoplotSpinner.Enable   = false;
%                 else
%                     app.SettoplotSpinner.Limits   = [1, app.SetsperTimeFrameEditField.Value];
%                     app.SettoplotSpinner.Enable   = true;
%                 end
%             else
%                 app.FrameToPlotSpinner.Enable = false;
%                 app.SettoplotSpinner.Enable   = false;    
%             end
        end

        function setPositionsFromCalib(app, stages)
            app.importedLimits = stages;
        end
        
        function updateConsZLabel(app)
            app.mmLabel_15.Text = sprintf("%.2f[mm]",app.consData.zVecSpinner(app.ZCoorConsSpinner.Value));
        end
    
        function updatePlotZSpinner(app)
%             if app.consData.varsValid
%                 app.ZCoorConsSpinner.Limits = [1,length(app.consData.zVec)];
%                 app.ZCoorConsSpinner.Step   = 1;
%                 app.ZCoorConsSpinner.Value  = 1;
%                 app.updateConsZLabel()
%                 app.ZCoorConsSpinner.Enable = true;
%             else
%                 app.ZCoorConsSpinner.Enable = false;
%                 app.ZCoorConsSpinner.Value  = 1;
%             end
        end

        %---------------- Algo Functions ---------------%
        function initAlgoVars(app)
           app.algoData.uVars = Algo.uVarsCreate(); 
           app.collectAlgoVars();

           app.algoData.uVars.bufferSizeBytes   = 8*2^20;
           app.algoData.uVars.bytesPerSample    = 2;
           app.algoData.uVars.preTriggerSamples = 14;

        end
        
        function collectAlgoVars(app) 
           app.algoData.uVars.c               = app.SpeedinMediumEditField.Value; 
           app.algoData.uVars.fSin            = app.SineFrequencyEditField.Value*1e6;              
           app.algoData.uVars.fTrain          = app.TrainFrequencyEditField.Value*1e3;
           app.algoData.uVars.cycInPulse      = app.SinePeriodsEditField.Value;
           app.algoData.uVars.channels        = app.NumberOfChannelsEditField.Value;
           app.algoData.uVars.c               = app.SpeedinMediumEditField.Value;  
           app.algoData.uVars.phantomDepth    = app.PhantomdepthEditField.Value*1e-2;
           app.algoData.uVars.distFromPhantom = app.TransducerdistanceEditField.Value*1e-2;
           app.algoData.uVars.fExtClk         = app.FrequencyEditField.Value*1e6; %fs
           app.algoData.uVars.fSclk           = app.SclkEditField.Value*1e6;
           app.algoData.uVars.extClkDcyc      = app.DutyCycleEditField.Value;
           app.algoData.uVars.useGPU          = false;
           app.algoData.uVars.useQuant        = app.UseTimeQuantCheckBox.Value;
           app.algoData.uVars.quantTime       = app.QuantTimeEditField.Value;
           app.algoData.uVars.useHadamard     = app.UseHadamardCodeCheckBox.Value;
           app.algoData.uVars.timeToSample    = app.TimetosampleEditField.Value;
       end
            
        function setAlgoValuesTable(app)
            data = app.extractStructDataToTable(app.algoData.curRes.extClk);
            app.UITable_6.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.usSignal);
            app.UITable_5.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.samples);
            app.UITable.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.timing);
            app.UITable_3.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.digitizer);
            app.UITable_4.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.len);
            app.UITable_2.Data = data;
            
            data = app.extractStructDataToTable(app.algoData.curRes.freq);
            app.UITable_7.Data = data;
        end
        
        %---------------- Acousto Optics Functions ---------------%       
        % Variables
        function uVars = collectAOVars(app)
            app.aoData.uVars.c                 = app.SpeedinMediumEditField.Value;
            app.aoData.uVars.fSin              = app.SineFrequencyEditField.Value*1e6;              
            app.aoData.uVars.fTrain            = app.TrainFrequencyEditField.Value*1e3;
            app.aoData.uVars.cycInPulse        = app.SinePeriodsEditField.Value; 
            
            app.aoData.uVars.phantomDepth      = app.PhantomdepthEditField.Value*1e-2;
            app.aoData.uVars.distFromPhantom   = app.TransducerdistanceEditField.Value*1e-2;
            app.aoData.uVars.fExtClk           = app.FrequencyEditField.Value*1e6;
            app.aoData.uVars.timeToSample      = app.TimetosampleEditField.Value;
            app.aoData.uVars.extClkDcyc        = app.DutyCycleEditField.Value;
            app.aoData.uVars.fastAnalysis      = app.FastAnalysisCheckBox.Value;
             

            app.aoData.uVars.useQuant          = app.UseTimeQuantCheckBox.Value;
            app.aoData.uVars.quantTime         = app.QuantTimeEditField.Value;
            app.aoData.uVars.useHadamard       = app.UseHadamardCodeCheckBox.Value;
            
            app.aoData.uVars.fSclk             = app.SclkEditField.Value*1e6;
            
            app.aoData.uVars.channels          = app.NumberOfChannelsEditField.Value;
            
            app.aoData.uVars.useGPU            = app.UseGPUCheckBox.Value;
            
            % Stages control Vars
%             if app.MeasureatcurrentposCheckBox.Value
%                 app.aoData.uVars.stages.xPos       = [];
%                 app.aoData.uVars.stages.yPos       = [];
%             else
%                 app.aoData.uVars.stages.xPos       = app.XpositionEditField_2.Value;
%                 app.aoData.uVars.stages.yPos       = app.YpositionEditField_2.Value;
%             end
            
            app.collectAOGraphicVars();
            
            % set file system properties
            app.collectAOFileSystemVars();
            
            % Set Acousto Optics Object Control Vars
            app.aoData.uVars.useLoadedRawData = false;
            app.aoData.uVars.useVirtualData   = app.UseVirtualDataCheckBox.Value;
            app.aoData.uVars.loadDataFilename = app.DataEditField.Value;
            app.aoData.uVars.measHere         = app.MeasureatcurrentposCheckBox.Value;
            app.aoData.uVars.limitByN         = app.LimitLiveAOCheckBox.Value;
            app.aoData.uVars.N                = app.NEditField.Value;
            app.aoData.dispTimeTable          = app.DisplayTimeTableCheckBox.Value;
            
            uVars = app.aoData.uVars;
        end
        
        function fileSystem = collectAOFileSystemVars(app)
            app.aoData.uVars.fileSystem.saveRawData        = app.RawDataCheckBox.Value;
            app.aoData.uVars.fileSystem.saveNetSignal      = app.NetSignalCheckBox.Value;
            app.aoData.uVars.fileSystem.saveDemultiplexed  = app.DeMultiplexedCheckBox.Value;
            app.aoData.uVars.fileSystem.saveReshapedSignal = app.ReshapedCheckBox_2.Value;
            app.aoData.uVars.fileSystem.saveFFT            = app.FFTCheckBox_2.Value;
            app.aoData.uVars.fileSystem.savePhiChCmplx     = app.PhiChCmplxCheckBox.Value;
            
            app.aoData.uVars.fileSystem.saveResults = app.SaveResultsandVarsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveFigs    = app.SaveFigsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveVars    = app.SaveResultsandVarsCheckBox.Value;
            
            app.aoData.uVars.fileSystem.scanName    = app.MeasurementNameEditField.Value;
            app.aoData.uVars.fileSystem.dirPath     = app.DirectoryEditField.Value;
            
            app.aoData.uVars.fileSystem.extProject         = false;
            app.aoData.uVars.fileSystem.extProjPath        = [];
            app.aoData.uVars.fileSystem.extProjResultsPath = [];
            app.aoData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.aoData.uVars.fileSystem;
        end
    
        function figs = collectAOGraphicVars(app)
            % Choose channel and position            
            app.aoData.uVars.figs.ch    = app.PlotChannelSpinner.Value;
            app.aoData.uVars.figs.zIdx  = app.AOZIdxSpinner.Value;
            app.aoData.uVars.figs.quant = app.QuantAOSpinner.Value;
            
            app.aoData.uVars.figs.FFTenv         = app.FreqEnviromentEditField.Value*1e3;
            app.aoData.uVars.figs.displayFullFFT = app.dispFullFFTCheckBox.Value;
            
            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.aoData.uVars.figs.intExt = 'int';
            else
                app.aoData.uVars.figs.intExt = 'ext';
            end
            
            % Turn figures on and off
            app.aoData.uVars.figs.validStruct.extClk         = app.ExternalClkCheckBox.Value;
            app.aoData.uVars.figs.validStruct.usSignal       = app.UltrasoundSignalCheckBox.Value;
            app.aoData.uVars.figs.validStruct.fullSignal     = app.FullSignalCheckBox.Value;
            app.aoData.uVars.figs.validStruct.measSamples    = app.MeasuredSamplesCheckBox.Value;
            app.aoData.uVars.figs.validStruct.netSignal      = app.NetSamplesCheckBox.Value;
            app.aoData.uVars.figs.validStruct.deMul          = app.DemultiplexedCheckBox.Value;
            app.aoData.uVars.figs.validStruct.reshapedSignal = app.ReshapedCheckBox_3.Value;
            app.aoData.uVars.figs.validStruct.FFT            = app.FFTCheckBox_3.Value;
            app.aoData.uVars.figs.validStruct.qAvgFFT        = app.QuantAvgFFTCheckBox.Value;
            app.aoData.uVars.figs.validStruct.phiCh          = app.PhiChCheckBox.Value;
            app.aoData.uVars.figs.validStruct.phi            = app.PhiCheckBox.Value;
            
            figs = app.aoData.uVars.figs;
            
        end
    
        function initAOVars(app)
            % These are static variables that shouldn't be changed during operation
            % These variables are initialized and never changed again.
            app.aoData.uVars = acoustoOptics.uVarsCreate();
            
            app.aoData.uVars.IOPort            = 1;
            app.aoData.uVars.IOLine            = 4;

            app.collectAOVars();
            app.collectAOGraphicVars();
            
            app.aoData.uVars.figs.fonts.titleSize  = 14;
            app.aoData.uVars.figs.fonts.labelsSize = 14;
            app.aoData.uVars.figs.fonts.axisSize   = 14;
            
            % Handles
            app.aoData.uVars.figs.extH.extClk.fig         = app.UIFigure;
            app.aoData.uVars.figs.extH.extClk.ax          = app.axExtClk;
            app.aoData.uVars.figs.extH.usSignal.fig       = app.UIFigure;
            app.aoData.uVars.figs.extH.usSignal.ax        = app.axUsSignal;
            app.aoData.uVars.figs.extH.fullSignal.fig     = app.UIFigure;
            app.aoData.uVars.figs.extH.fullSignal.ax      = app.axFullSignal;
            app.aoData.uVars.figs.extH.measSamples.fig    = app.UIFigure;
            app.aoData.uVars.figs.extH.measSamples.ax     = app.axMeasSig;
            app.aoData.uVars.figs.extH.netSignal.fig      = app.UIFigure;
            app.aoData.uVars.figs.extH.netSignal.ax       = app.axNetSignal;
            app.aoData.uVars.figs.extH.deMul.fig          = app.UIFigure;
            app.aoData.uVars.figs.extH.deMul.ax           = app.axDeMul;
            app.aoData.uVars.figs.extH.reshapedSignal.fig = app.UIFigure;
            app.aoData.uVars.figs.extH.reshapedSignal.ax  = app.axReshape;
            app.aoData.uVars.figs.extH.FFT.fig            = app.UIFigure;
            app.aoData.uVars.figs.extH.FFT.ax             = app.axFFTsm;
            app.aoData.uVars.figs.extH.qAvgFFT.fig        = app.UIFigure;
            app.aoData.uVars.figs.extH.qAvgFFT.ax         = app.axQAvgFFT;
            app.aoData.uVars.figs.extH.phiCh.fig          = app.UIFigure;
            app.aoData.uVars.figs.extH.phiCh.ax           = app.axPhiCh;
            app.aoData.uVars.figs.extH.phi.fig            = app.UIFigure;
            app.aoData.uVars.figs.extH.phi.ax             = app.axPhi;
        end

        function setAOVarsToGUI(app, vars)
            app.SpeedinMediumEditField.Value  = vars.c;
            app.SineFrequencyEditField.Value  = vars.fSin/1e6;             
            app.TrainFrequencyEditField.Value = vars.fTrain/1e3;
            app.SinePeriodsEditField.Value    = vars.cycInPulse; 
            
            app.PhantomdepthEditField.Value       = vars.phantomDepth/1e-2;
            app.TransducerdistanceEditField.Value = vars.distFromPhantom/1e-2;
            app.FrequencyEditField.Value          = vars.fExtClk/1e6;
            app.TimetosampleEditField.Value       = vars.timeToSample;
            app.DutyCycleEditField.Value          = vars.extClkDcyc;
            app.FastAnalysisCheckBox.Value        = vars.fastAnalysis;
             
            app.UseTimeQuantCheckBox.Value    = vars.useQuant;
            app.QuantTimeEditField.Value      = vars.quantTime;
            app.UseHadamardCodeCheckBox.Value = vars.useHadamard;
            
            app.SclkEditField.Value             = vars.fSclk/1e6;
            
            app.NumberOfChannelsEditField.Value = vars.channels;
            
            app.UseGPUCheckBox.Value            = vars.useGPU;
        end
        
        % GUI Control
        function updateAOPlotChSpinnerLimits(app)
            channels = app.aoData.curVars.measVars.algo.digitizer.channels;
            if app.PlotChannelSpinner.Value > channels
                app.PlotChannelSpinner.Value = 1;
            end
            app.PlotChannelSpinner.Limits = [1, channels];
        end
        
        function updateAOPlotZIdxSpinnerLimits(app)
                zVec = app.aoData.curVars.measVars.algo.len.zVecUSRes;
                app.AOZIdxSpinner.Step  = 1;
                if  app.AOZIdxSpinner.Value > length(zVec)
                    app.AOZIdxSpinner.Value = 1;
                    app.updateAOZIdxSpinnerLabel()
                end  
                app.AOZIdxSpinner.Limits = [1, length(zVec)];
        end
        
        function updateQuantSpinnerLimits(app)
            maxQuant = app.aoData.curVars.measVars.algo.samples.numOfQuant;
            if app.QuantAOSpinner.Value > maxQuant
                app.QuantAOSpinner.Value  = 1;
                app.QuantAOSpinner.Step   = 1;
            end
            app.QuantAOSpinner.Limits = [1, maxQuant]; 
        end
        
        function updateAOZIdxSpinnerLabel(app)
            app.mmLabel_16.Text = sprintf("%.2f[mm]", app.aoData.curVars.measVars.algo.len.zVecUSRes(app.AOZIdxSpinner.Value)*1e3);
        end
       
        %Figures
        function updateAOFigs(app)
            if ~isempty(app.aoData.curRes)
                figsVars = app.collectAOGraphicVars();
                app.ao.setFigsVars(figsVars);
            end
        end
        
        %---------------- Scan 1D Functions ---------------%
        % Variables
        function staticVars = uVarsS1dInit(app)
           app.s1dData.uVars = scan1D.uVarsCreate();
           
           app.s1dData.uVars.figs.extH.curMainAxis.fig     = app.UIFigure;
           app.s1dData.uVars.figs.extH.curMainAxis.ax      = app.axMainAxis;
           app.s1dData.uVars.figs.extH.curMainAxisAvg.fig  = app.UIFigure;
           app.s1dData.uVars.figs.extH.curMainAxisAvg.ax   = app.axMainAxisAvg;
           app.s1dData.uVars.figs.extH.curMainPlane.fig    = app.UIFigure;
           app.s1dData.uVars.figs.extH.curMainPlane.ax     = app.axMainPlane;
           app.s1dData.uVars.figs.extH.curMainPlaneAvg.fig = app.UIFigure;
           app.s1dData.uVars.figs.extH.curMainPlaneAvg.ax  = app.axMainPlaneAvg;

           app.s1dData.uVars.figs.fonts.type = [];
           app.s1dData.uVars.figs.fonts.titleSize  = 14;
           app.s1dData.uVars.figs.fonts.labelsSize = 14;
           app.s1dData.uVars.figs.fonts.axisSize   = 14;

           app.s1dData.uVars.figs.validStruct.curMainAxis     = true;
           app.s1dData.uVars.figs.validStruct.curMainAxisAvg  = true;
           app.s1dData.uVars.figs.validStruct.curMainPlane    = true;
           app.s1dData.uVars.figs.validStruct.curMainPlaneAvg = true;
           
           staticVars.validStruct = app.s1dData.uVars.figs.validStruct;
           staticVars.intExt      = app.s1dData.uVars.figs.intExt;
           staticVars.extH        = app.s1dData.uVars.figs.extH;

        end 
        
        function collectS1DVars(app)         
            % Acousto Optics variables
            app.s1dData.uVars.ao = app.collectAOVars;
            
            % Grid scan variables
            app.s1dData.uVars.grid.startFirst  = app.S1DXstartpointEditField.Value;
            app.s1dData.uVars.grid.endFirst    = app.S1DXendpointEditField.Value;
            app.s1dData.uVars.grid.strideFirst = app.S1DXstrideEditField.Value;
                
            app.s1dData.uVars.grid.scanAxLabel  = app.S1DScanAxisDropDown.Value;
            app.s1dData.uVars.grid.depthAxLabel = app.S1DDepthAxisDropDown.Value;
            app.s1dData.uVars.grid.fixedAxPos   = app.S1DFixedAxPosEditField.Value;
            
            % General scan variables
            app.s1dData.uVars.general.useQuant = app.UseTimeQuantCheckBox.Value;
            app.s1dData.uVars.general.repeats  = app.S2DRepeatsEditField.Value;
            
            % Figures variables
            app.collectS1DGraphicsVars();
            
            % File system variables
            app.collectS1DFileSystemVars;
        end
        
        function figs = collectS1DGraphicsVars(app)
            app.s1dData.uVars.figs.depthIdx  = app.S1DDepthCoorSpinner.Value;
            if app.ExternalFiguresCheckBox.Value
                app.s1dData.uVars.figs.intExt = 'int';
            else
                app.s1dData.uVars.figs.intExt = 'ext';
            end
            figs = app.s1dData.uVars.figs;
        end
        
        function fileSystem = collectS1DFileSystemVars(app)
            app.s1dData.uVars.fileSystem.saveResults = app.SaveResultsandVarsCheckBox.Value;
            app.s1dData.uVars.fileSystem.saveVars    = app.SaveResultsandVarsCheckBox.Value;
            app.s1dData.uVars.fileSystem.saveFigs    = app.SaveFigsCheckBox.Value;
            
            app.s1dData.uVars.fileSystem.scanName    = app.MeasurementNameEditField.Value;
            app.s1dData.uVars.fileSystem.dirPath     = app.DirectoryEditField.Value;
            
            app.s1dData.uVars.fileSystem.extProject         = false;
            app.s1dData.uVars.fileSystem.extProjPath        = [];
            app.s1dData.uVars.fileSystem.extProjResultsPath = [];
            app.s1dData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.s1dData.uVars.fileSystem; 
        end
        
        % GUI
        function updateS1DDepthAxisSpinnerLabel(app)
            idx = app.S1DDepthCoorSpinner.Value;
            app.mmLabel_24.Text = sprintf("%.2f[mm]", app.s1dData.curVars.ao.measVars.algo.len.zVecUSRes(idx)*1e3);
        end
        
        function updateS1DDepthCoorSpinnerLimits(app)
            maxValue = length(app.s1dData.curVars.ao.measVars.algo.len.zVecUSRes);
            app.S1DDepthCoorSpinner.LowerLimitInclusive = 'on';
            app.S1DDepthCoorSpinner.Step   = 1;
            if app.S1DDepthCoorSpinner.Value > maxValue
                app.S1DDepthCoorSpinner.Value  = 1;
                app.updateS1DDepthAxisSpinnerLabel()
            end
            app.S1DDepthCoorSpinner.Limits = [1, maxValue];
        end 
        
%         function updateS1DRepeatsSpinnerLimits(app)
%             app.S1DRepeatSpinner.LowerLimitInclusive = 'on';
%             app.S1DRepeatSpinner.Step   = 1;
%             if app.s1dData.curVars.general.repeats > 1
%                maxRep = app.s1dData.curVars.general.repeats;
%                app.S1DRepeatSpinner.Value  = 1;
%                app.S1DRepeatSpinner.Limits = [1, maxRep];
%                app.S1DRepeatSpinner.Enable = true;
%             else
%                app.S1DRepeatSpinner.Value  = 1;
%                app.S1DRepeatSpinner.Limits = [0,1];
%                app.S1DRepeatSpinner.Enable = false;
%             end
%         end
        
        function updateS1DGUIComponents(app)
%             app.S1DRepeatSpinner.Enable     = false;
            app.S1DDepthCoorSpinner.Enable  = false;
 
            app.updateS1DDepthCoorSpinnerLimits();
%             app.updateS1DRepeatsSpinnerLimits();
            
%             app.S1DRepeatSpinner.Enable    = true;
            app.S1DDepthCoorSpinner.Enable  = true;
        end
        
        % Figures
        
        %---------------- Scan 2D Functions ---------------%
        % Variables
        function staticVars = uVarsS2dInit(app)
           app.s2dData.uVars = scan3DAO.uVarsCreate();
           
           app.s2dData.uVars.figs.extH.curMainAxis.fig     = app.UIFigure;
           app.s2dData.uVars.figs.extH.curMainAxis.ax      = app.axMainAxis;
           app.s2dData.uVars.figs.extH.curMainAxisAvg.fig  = app.UIFigure;
           app.s2dData.uVars.figs.extH.curMainAxisAvg.ax   = app.axMainAxisAvg;
           app.s2dData.uVars.figs.extH.curMainPlane.fig    = app.UIFigure;
           app.s2dData.uVars.figs.extH.curMainPlane.ax     = app.axMainPlane;
           app.s2dData.uVars.figs.extH.curMainPlaneAvg.fig = app.UIFigure;
           app.s2dData.uVars.figs.extH.curMainPlaneAvg.ax  = app.axMainPlaneAvg;
           app.s2dData.uVars.figs.extH.nav.fig             = app.UIFigure;
           app.s2dData.uVars.figs.extH.nav.ax              = app.axNav;
           app.s2dData.uVars.figs.extH.navAvg.fig          = app.UIFigure;
           app.s2dData.uVars.figs.extH.navAvg.ax           = app.axNavAvg;
           
           app.s2dData.uVars.figs.fonts.type = [];
           app.s2dData.uVars.figs.fonts.titleSize  = 14;
           app.s2dData.uVars.figs.fonts.labelsSize = 14;
           app.s2dData.uVars.figs.fonts.axisSize   = 14;

           app.s2dData.uVars.figs.validStruct.curMainAxis     = true;
           app.s2dData.uVars.figs.validStruct.curMainAxisAvg  = true;
           app.s2dData.uVars.figs.validStruct.curMainPlane    = true;
           app.s2dData.uVars.figs.validStruct.curMainPlaneAvg = true;
           app.s2dData.uVars.figs.validStruct.nav             = true;
           app.s2dData.uVars.figs.validStruct.navAvg          = true;
           
           staticVars.validStruct = app.s2dData.uVars.figs.validStruct;
           staticVars.intExt      = app.s2dData.uVars.figs.intExt;
           staticVars.extH        = app.s2dData.uVars.figs.extH;

        end 

        function collectS2DVars(app)         
            % Acousto Optics variables
            app.s2dData.uVars.ao = app.collectAOVars;
%             app.deactivateAOfs(); %why? let's integrate!
            
            % Grid scan variables
            app.s2dData.uVars.grid.startFirst   = app.S2DFirstStartEditField.Value;
            app.s2dData.uVars.grid.strideFirst  = app.S2DFirstStrideEditField.Value;
            app.s2dData.uVars.grid.endFirst     = app.S2DFirstEndEditField.Value;
            
            app.s2dData.uVars.general.scan2D = app.Perform2DscanCheckBox.Value;
            if app.s2dData.uVars.general.scan2D
                app.s2dData.uVars.grid.secondPos = app.S2DSecondStartEditField.Value;
            else
                app.s2dData.uVars.grid.startSecond  = app.S2DSecondStartEditField.Value;
                app.s2dData.uVars.grid.strideSecond = app.S2DSecondStrideEditField.Value;
                app.s2dData.uVars.grid.endSecond    = app.S2DSecondEndEditField.Value;
            end
            
            app.s2dData.uVars.grid.firstAxis  = app.S2DFirstAxisDropDown.Value;
            app.s2dData.uVars.grid.secondAxis = app.S2DSecondAxisDropDown.Value;
            
            % General scan variables
            app.s2dData.uVars.general.useQuant = app.UseTimeQuantCheckBox.Value;
            app.s2dData.uVars.general.repeats = app.S2DRepeatsEditField.Value;
            
            % Figures variables
            app.collectS2DGraphicsVars();
            
            % File system variables
            app.collectS2DFileSystemVars;
        end
        
        function figs = collectS2DGraphicsVars(app)
            app.s2dData.uVars.figs.depthIdx  = app.S2dCurDepthCoorSpinner.Value;
            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.s2dData.uVars.figs.intExt = 'int';
            else
                app.s2dData.uVars.figs.intExt = 'ext';
            end
            app.s2dData.uVars.figs.normColorsToPlane = app.NormalizeColorstoDisplayedPlaneCheckBox.Value;
            
            figs = app.s2dData.uVars.figs;
        end
        
        function setS2DVarsToGUI(app, vars)
            % Grid scan variables
            app.S2DFirstStartEditField.Value   = vars.grid.startFirst;
            app.S2DSecondStartEditField.Value  = vars.grid.startSecond;
            app.S2DFirstEndEditField.Value     = vars.grid.endFirst;
            app.S2DSecondEndEditField.Value    = vars.grid.endSecond;
            app.S2DFirstStrideEditField.Value  = vars.grid.strideFirst;
            app.S2DSecondStrideEditField.Value = vars.grid.strideSecond;
            app.S2DFirstAxisDropDown.Value     = vars.grid.firstAxis;
            switch app.S2DFirstAxisDropDown.Value
                case 'X'
                    app.S2DSecondAxisDropDown.Items = {'Y', 'Z'};
                    app.S2DSecondAxisDropDown.Value = 'Z';
                case 'Y'
                    app.S2DSecondAxisDropDown.Items = {'X', 'Z'};
                    app.S2DSecondAxisDropDown.Value = 'Z';
                case 'Z'
                    app.S2DSecondAxisDropDown.Items = {'X', 'Y'};
                    app.S2DSecondAxisDropDown.Value = 'Y';   
            end
            app.S2DSecondAxisDropDown.Value = vars.grid.secondAxis;
        end
        
        function navVars = collectS2DNavVars(app)
            navVars = scan3DGraphics.createNavigatorVars();
            navVars.navAx  = app.S2dNavPlainDropDown.Value;
            navVars.navIdx = app.S2dNavIdxSpinner.Value;
            navVars.navRep = app.S2dRepeatSpinner.Value;
            app.s2dData.navVars = navVars;
        end
        
        function fileSystem = collectS2DFileSystemVars(app)
            app.s2dData.uVars.fileSystem.saveResults = app.SaveResultsandVarsCheckBox.Value;
            app.s2dData.uVars.fileSystem.saveVars    = app.SaveResultsandVarsCheckBox.Value;
            app.s2dData.uVars.fileSystem.saveFigs    = app.SaveFigsCheckBox.Value;
            
            app.s2dData.uVars.fileSystem.scanName    = app.MeasurementNameEditField.Value;
            app.s2dData.uVars.fileSystem.dirPath     = app.DirectoryEditField.Value;
            
            app.s2dData.uVars.fileSystem.extProject         = false;
            app.s2dData.uVars.fileSystem.extProjPath        = [];
            app.s2dData.uVars.fileSystem.extProjResultsPath = [];
            app.s2dData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.s2dData.uVars.fileSystem; 
        end
        
        % GUI Control
        function updateS2DNavIdxSpinnerLimits(app)
           plain = app.S2dNavPlainDropDown.Value;
           app.S2dNavIdxSpinner.LowerLimitInclusive = 'on';
           app.S2dNavIdxSpinner.Step  = 1;
           switch plain
                case 'X'
                    maxLim = length(app.s2dData.curVars.grid.xVec);
                case 'Y'
                    maxLim = length(app.s2dData.curVars.grid.yVec);
                case 'Z'
                    maxLim = length(app.s2dData.curVars.grid.zVec);
           end     
           app.S2dNavIdxSpinner.Value = 1;
           app.updateS2DNavIdxLabel();
           app.S2dNavIdxSpinner.Limits = [1, maxLim];
        end
        
        function updateS2DRepeatsSpinnerLimits(app)
            app.S2dRepeatSpinner.LowerLimitInclusive = 'on';
            app.S2dRepeatSpinner.Step   = 1;
            if app.s2dData.curVars.general.repeats > 1
               maxRep = app.s2dData.curVars.general.repeats;
               app.S2dRepeatSpinner.Value  = 1;
               app.S2dRepeatSpinner.Limits = [1, maxRep];
               app.S2dRepeatSpinner.Enable = true;
            else
               app.S2dRepeatSpinner.Value  = 1;
               app.S2dRepeatSpinner.Limits = [0,1];
               app.S2dRepeatSpinner.Enable = false;
            end
        end
 
        function updateS2DMainAxisZSpinnerLimits(app)
            maxValue = length(app.s2dData.curVars.ao.measVars.algo.len.zVecUSRes);
            app.S2dCurDepthCoorSpinner.LowerLimitInclusive = 'on';
            app.S2dCurDepthCoorSpinner.Step   = 1;
            if app.S2dCurDepthCoorSpinner.Value > maxValue
                app.S2dCurDepthCoorSpinner.Value  = 1;
                app.updateS2DMainAxisZSpinnerLabel()
            end
            app.S2dCurDepthCoorSpinner.Limits = [1, maxValue];
        end
        
        function updateS2DNavIdxLabel(app)
           plain = app.S2dNavPlainDropDown.Value;
           idx = app.S2dNavIdxSpinner.Value;
           switch plain
               case 'X'
                   app.mmLabel_14.Text = sprintf("%.2f[mm]", app.s2dData.curVars.grid.xVec(idx));
               case 'Y'
                   app.mmLabel_14.Text = sprintf("%.2f[mm]", app.s2dData.curVars.grid.yVec(idx));
               case 'Z'
                   app.mmLabel_14.Text = sprintf("%.2f[mm]", app.s2dData.curVars.ao.measVars.algo.len.zVecUSRes(idx)*1e3);
           end
        end
    
        function updateS2DMainAxisZSpinnerLabel(app)
            idx = app.S2dCurDepthCoorSpinner.Value;
            app.mmLabel_19.Text = sprintf("%.2f[mm]", app.s2dData.curVars.ao.measVars.algo.len.zVecUSRes(idx)*1e3);
        end
        
        function updateS2DGUIComponents(app)
            app.S2dNavPlainDropDown.Enable = false;
            app.S2dNavIdxSpinner.Enable    = false;
            app.S2dRepeatSpinner.Enable    = false;
            app.S2dCurDepthCoorSpinner.Enable  = false;
 
            app.updateS2DMainAxisZSpinnerLimits();
            app.updateS2DRepeatsSpinnerLimits();
            app.updateS2DNavIdxSpinnerLimits();

            app.S2dNavPlainDropDown.Enable = true;
            app.S2dNavIdxSpinner.Enable    = true;
            app.S2dCurDepthCoorSpinner.Enable  = true;
        end

        %Figures
        function updateS2DNavigator(app)
            navVars = app.collectS2DNavVars();
            app.s2dGraphics.setNavVars(navVars);
            app.s2dGraphics.dispNavPlane();
            app.s2dGraphics.dispNavPlaneAvg();
        end
        
        function updateS2DCurFigures(app)
            idx = app.S2dCurDepthCoorSpinner.Value;
            app.s2dGraphics.setDepthToMainAxis(idx);
            app.s2dGraphics.dispCurMainAxis();
            app.s2dGraphics.dispCurMainAxisAvg();
        end
         %---------------- Consistency Functions ---------------%    
        function uVarsConsisInit(app)
           app.uVarsConsistency = Consistency.uVarsCreate();
           
           app.uVarsConsistency.gReq.extH.allFrames.fig  = app.UIFigure;
           app.uVarsConsistency.gReq.extH.allFrames.ax   = app.axAllFrames;
           app.uVarsConsistency.gReq.extH.curFrame.fig   = app.UIFigure;
           app.uVarsConsistency.gReq.extH.curFrame.ax    = app.axCurFrame;
           app.uVarsConsistency.gReq.extH.curSet.fig     = app.UIFigure;
           app.uVarsConsistency.gReq.extH.curSet.ax      = app.axCurSet;
           app.uVarsConsistency.gReq.extH.navFrame.fig   = app.UIFigure;
           app.uVarsConsistency.gReq.extH.navFrame.ax    = app.axNavFrame;
           app.uVarsConsistency.gReq.extH.navSet.fig     = app.UIFigure;
           app.uVarsConsistency.gReq.extH.navSet.ax      = app.axNavSet;
           
        end
    
        function uVarsConsisUpdate(app)
            
            app.collectAOVars;
            app.deactivateAOfs();
            app.uVarsConsistency.acoustoOptics = app.aoData.uVars;

            app.uVarsConsistency.stages.xPos = app.XpositionEditField.Value;
            app.uVarsConsistency.stages.yPos = app.YpositionEditField.Value;
            
            app.uVarsConsistency.fileSystem.scanName        = app.MeasurementNameEditField.Value;
            app.uVarsConsistency.fileSystem.resDirPath      = app.DirectoryEditField.Value;
            app.uVarsConsistency.fileSystem.saveFullData    = false;
            app.uVarsConsistency.fileSystem.saveReducedData = false;
            app.uVarsConsistency.fileSystem.saveFigs        = app.SaveFigsCheckBox.Value;
            app.uVarsConsistency.fileSystem.saveResults     = app.SaveResultsandVarsCheckBox.Value;
            
            app.uVarsConsistency.scan.startTime = app.MinTimeFrameDurationEditField.Value;
            app.uVarsConsistency.scan.stride    = app.TimeStrideEditField.Value;
            app.uVarsConsistency.scan.endTime   = app.MaxTimeFrameDurationEditField.Value;
            app.uVarsConsistency.scan.useQuant  = app.UseTimeQuantCheckBox.Value;
            app.uVarsConsistency.scan.quantTime = app.QuantTimeEditField.Value;
            app.uVarsConsistency.scan.numOfSets = app.SetsperTimeFrameEditField.Value;

            app.uVarsConsistency.gReq.ch    = app.PlotChannelSpinner.Value;
            app.uVarsConsistency.gReq.zIdx  = app.ZCoorConsSpinner.Value;
            app.uVarsConsistency.gReq.quant = 1;
            
            % Choose internal or external
            if app.ExternalFiguresCheckBox.Value
                app.uVarsConsistency.gReq.intExt = 'int';
            else
                app.uVarsConsistency.gReq.intExt = 'ext';
            end

            names = fieldnames(app.uVarsConsistency.gReq.validStruct);
            for i=1:length(names)
                app.uVarsConsistency.gReq.validStruct.(names{i}) = true;
            end
        end
 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            f = waitbar(0,'Starting GUI: Resetting Peripherals');
            instrreset;
%             app.AOData.zVec = [];
%             app.AOData.numOfQuants = [];
            
            % --------- Algorithm ------- %
            waitbar(.2,f,'Initing Algorithm Object');
            app.initAlgoVars();
            app.algo = Algo();

            %-------- Acousto Optics ---------%
            waitbar(.4,f,'Initing Acousto Optics Object');
            app.initAOVars();
            app.ao = acoustoOptics();
            app.ao.init(app);
            app.aoGraphics = app.ao.getGraphicsHandle();

            app.aoData.importedVars = [];
            app.aoData.importedRes  = [];
            app.aoData.curtVars     = [];
            app.aoData.curRes       = [];
            
%             app.aoListeners{1} = listener(app,'timeTable',@app.displayAOTimeTable); 
            
            app.GUIctrl.restartAO = true;  % to init AO object in the beginning of the first run
            app.GUIctrl.liveAO    = false; % for toggling the button Run Live \ Stop Live
            
            %-------- Stages ---------%
            waitbar(.6,f,'Connecting to Stages');
            app.aoStages = stages('COM3');
            app.aoStages.connect();
            app.stagesData.avail = app.aoStages.hardwareAvailable;
            if ~app.stagesData.avail
                uiwait(msgbox('NOTICE: No stages Are available'));
                app.stage1IDDropDown.Items = 'No Stages';
                app.stage1IDDropDown.Value = 'No Stages';
                app.stage2IDDropDown.Items = 'No Stages';
                app.stage2IDDropDown.Value = 'No Stages';
                app.stagesData.stage1Id = 0;
                app.stagesData.stage1Ax = 'X';
                app.stagesData.stage2Id = 0;
                app.stagesData.stage2Ax = 'Y';
                app.SetStagesGeometryButton.Enable = false;
            else
                app.stagesData.devicesIDs  = app.aoStages.getDevicesIDs();
                app.stagesData.numOfStages = app.aoStages.vars.numOfStages;
                for i = 1:app.stagesData.numOfStages
                    devicesIdsStr{i} = num2str(app.stagesData.devicesIDs(i));
                end
                app.stage1IDDropDown.Items = ['Not Assigned', devicesIdsStr];
                app.stage1IDDropDown.Value = 'Not Assigned';
                app.stage2IDDropDown.Items = ['Not Assigned', devicesIdsStr];
                app.stage2IDDropDown.Value = 'Not Assigned';
                app.stagesData.stage1Id = 0;
                app.stagesData.stage1Ax = 'X';
                app.stagesData.stage2Id = 0;
                app.stagesData.stage2Ax = 'Y';
                app.SetStagesGeometryButton.Enable = false;
            end
            
            %-------- Scan 2D --------%
            waitbar(.6,f,'Creatign 3D Scan Object');
            app.uVarsS2dInit();
            app.s2d            = scan3DAO(app.ao, app.aoStages);
            app.s2dGraphics    = app.s2d.getGraphicsHandle();
            
            app.s2dData.importedVars = [];
            app.s2dData.importedRes  = [];
            app.s2dData.curtVars     = [];
            app.s2dData.curRes       = [];
            
            %-------- Consistency ---------%
%             app.consistency = Consistency(app.ao, [], app);
%             app.hConsGraph  = app.consistency.getGraphicsHandle();
%             app.uVarsConsisInit();
%             
%             app.initConnsistency        = true;
%             app.consStagesRecon         = true;
% %             app.consData.quantDataValid = false;
%             app.consData.setDataValid   = false;
%             app.consData.frameDataValid = false;
%             app.consData.varsValid      = false;
%             
% %             app.consListeners{1} = listener(app,'updateQuant',@app.updateCurrentSetPlots);
%             app.consListeners{1} = listener(app,'updateSet',  @app.updateCurrentFramePlots);
%             app.consListeners{2} = listener(app,'updateFrame',@app.updateTotalFramePlots);

            %---- GUI Default Params ---- %
%             app.updatePlotZSpinner();
%             app.updateMainZSpinner();
%             app.updateChannelSpinner();
%             app.updateFrameAndSetSpinners();
            close(f)
        end

        % Button pushed function: RunButtonAO
        function RunButtonAOPushed(app, event)
            if app.GUIctrl.restartAO
                app.ao.init();
                app.GUIctrl.restartAO = false;
            end
            app.collectAOVars();
            app.ao.setMeasVars(app.aoData.uVars);
            app.ao.configPeripherals();
            app.aoData.curVars = app.ao.getAOVars();
%             app.algoValues  = app.ao.algo.getVars();
%             app.AOData.zVec = app.algo.len.zVecUSRes;

            t = tic;
            if app.MeasureatcurrentposCheckBox.Value
                app.aoData.curRes = app.ao.measureAndAnalyse();
            else
                pos = [app.XpositionEditField_2.Value, app.YpositionEditField_2.Value];
                app.aoData.curRes = app.ao.moveMeasureAndAnalyse(pos);
            end
            t = toc(t);
            
            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',t}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.analyse}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
            dataAO(5,1:2) = {'Data to Graphics', timeTable.setResultsToGraphics};
            dataAO(6,1:2) = {'Plots', timeTable.plotAll};
            dataAO(7,1:2) = {'Save data', timeTable.moveData};
            
            app.UITable2.Data   = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;
        end

        % Button pushed function: RunLiveButton
        function RunLiveButtonPushed(app, event)
            if app.GUIctrl.liveAO 
                fprintf("GUI: Stopping Live AO, Please wait...\n");
                app.ao.runLive = false;
                app.RunLiveButton.Text = 'Run Live';
                app.GUIctrl.liveAO = false;
            else
                if app.GUIctrl.restartAO
                    app.ao.init();
                    app.GUIctrl.restartAO = false;
                end
                app.collectAOVars();
                
                app.ao.setMeasVars(app.aoData.uVars);
                app.ao.configPeripherals();
                
                % For spinner update:
                app.aoData.curVars = app.ao.getAOVars();
                %insert function that update the spiner limits
                
                app.RunLiveButton.Text = 'Stop Live';
                
                app.GUIctrl.liveAO = true;
                app.aoData.curRes = app.ao.liveAcoustoOptics();
                app.RunLiveButton.Text = 'Run Live';
                app.GUIctrl.liveAO = false;
            end
        end

        % Button pushed function: analysisVarsPathButton
        function analysisVarsPathButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            app.VarsEditField.Value = [path, filename]; 
            app.AnalyzeOrigButton.Enable = false;
            app.AnalyizeNewButton.Enable = false;
        end

        % Button pushed function: analysisDataPathButton
        function analysisDataPathButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            app.DataEditField.Value = [path, filename];
            app.AnalyzeOrigButton.Enable = false;
            app.AnalyizeNewButton.Enable = false;
        end

        % Button pushed function: ImportButton
        function ImporAOResultstButtonPushed(app, event)
            rawDataPath = app.DataEditField.Value;
            varsPath    = app.VarsEditField.Value;
            fprintf("GUI: Trying to load results and vars\n");
            rawData     = app.ao.loadRawDataToAO(rawDataPath);
            vars        = load(varsPath);
            if ~isempty(rawData) && ~isempty(vars) 
                app.AnalyzeOrigButton.Enable = true;
                app.AnalyizeNewButton.Enable = true;
                app.aoData.importedVars = vars;
                app.aoData.importedData = rawData;
                app.setAOVarsToGUI(vars.uVars);
                fprintf("GUI: Successfully loaded results and vars.\n");
            else
                app.AnalyzeOrigButton.Enable = false;
                app.AnalyizeNewButton.Enable = false;
                fprintf("GUI: Invalid files\n");
            end
        end

        % Button pushed function: AnalyzeOrigButton
        function AnalyzeOrigButtonPushed(app, event)
            vars = app.aoData.importedVars;
            
            app.setAOVarsToGUI(app.aoData.importedVars.uVars); %not sure this is convinient to the user
            
            app.collectAOGraphicVars();
            app.collectAOFileSystemVars();
             
            vars.uVars.figs       = app.aoData.uVars.figs; %plot analysed data according to user request
            vars.uVars.fileSystem = app.aoData.uVars.fileSystem; %save analysed data according to user request
            
            app.ao.setMeasVars(vars.uVars);
            app.aoData.curRes  = app.ao.analyseLoadedData();
            app.aoData.curVars = app.ao.getAOVars();
        end

        % Button pushed function: AnalyizeNewButton
        function AnalyizeNewButtonPushed(app, event)
            app.collectAOVars();
            app.ao.setMeasVars(app.aoData.uVars);
            app.aoData.curRes  = app.ao.analyseLoadedData();
            app.aoData.curVars = app.ao.getAOVars();
        end

        % Value changed function: AOZIdxSpinner
        function AOZIdxSpinnerValueChanged(app, event)
            app.updateAOZIdxSpinnerLabel();
            app.updateAOFigs();
        end

        % Button pushed function: StagesCalibToolButton
        function StagesCalibToolButtonPushed(app, event)
             app.s2dStagesRecon  = true;
             app.consStagesRecon = true;
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app);
        end

        % Button pushed function: S2DImportLimitsfromfileButton
        function S2DImportLimitsfromfileButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            app.importedLimits = load([path, filename]);
            applyImportedLimits(app);
        end

        % Menu selected function: PMTSaturationInspector
        function PMTSaturationInspectorMenuSelected(app, event)
            PMTSat;
        end

        % Menu selected function: ScanAssistantMenu
        function ScanAssistantMenuSelected(app, event)
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app)
        end

        % Value changed function: S2dNavPlainDropDown
        function S2dNavPlainDropDownValueChanged(app, event)
            app.updateS2DNavIdxSpinnerLimits();           
            app.updateS2DNavigator();
        end

        % Value changed function: S2dNavIdxSpinner
        function S2dNavIdxSpinnerValueChanged(app, event)
            app.updateS2DNavIdxLabel();
            app.updateS2DNavigator();
        end

        % Value changed function: S2dRepeatSpinner
        function S2dRepeatSpinnerValueChanged(app, event)
            app.updateS2DNavIdxLabel();
            app.updateS2DNavigator();
        end

        % Value changed function: S2dCurDepthCoorSpinner
        function S2dCurDepthCoorSpinnerValueChanged(app, event)
            app.updateS2DMainAxisZSpinnerLabel();
            app.updateS2DCurFigures();
        end

        % Button pushed function: S2DRunButton
        function S2DRunButtonPushed(app, event)
%              app.uVarsS2dUpdate()
            app.collectS2DVars();
            
            app.s2d.setUserVars(app.s2dData.uVars);
            app.s2dData.curVars = app.s2d.getScanVars();
            app.aoData.curVars  = app.s2dData.curVars.ao;
            
            app.updateS2DGUIComponents();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateQuantSpinnerLimits();
            
            app.s2dData.curRes  = app.s2d.scan3D();
            app.aoData.curRes   = app.ao.getResult;
        end

        % Callback function
        function S1DRunButtonPushed(app, event)
            %              app.uVarsS2dUpdate()
            app.collectS1DVars();
            
            app.s1d.setUserVars(app.s1dData.uVars);
            app.s1dData.curVars = app.s1d.getScanVars();
            app.aoData.curVars  = app.s1dData.curVars.ao;
            
            app.updateS1DGUIComponents();
            
            app.updateAOPlotChSpinnerLimits();
            app.updateAOPlotZIdxSpinnerLimits();
            app.updateQuantSpinnerLimits();
            
            app.s1d.configureScan();
            app.s1dData.curRes  = app.s1d.startScan();
            app.aoData.curRes   = app.ao.getResult;
        end

        % Button pushed function: S2DImportResultsButton
        function ImportS2DscanResultsButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', 'Select Scan Results *.mat file');
            if isempty(filename)
                fprintf("GUI: No loaded data\n");
                return;
            end
            h = waitbar(0,'Loading Results. Please wait...');
            data = load([path,filename]);
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', 'Select Scan Variables *.mat file');
            if isempty(filename)
                fprintf("GUI: No loaded vars\n");
                close(h);
                return;
            end
            waitbar(.5,h,'Loading Variables. Please wait');
            vars = load([path,filename]);
            waitbar(1,h,'Done');
            close(h)

            if isfield(vars, 'uVars')
                app.setS2DVarsToGUI(vars.uVars);
                app.setAOVarsToGUI(vars.uVars.ao);
                app.s2dData.curVars = vars;
                app.s2dData.curData = data;
                vars.uVars.figs = app.collectS2DGraphicsVars();
                app.s2d.setLoaded3DData(data, vars);
                app.updateS2DGUIComponents();
            else
                fprintf("GUI: Failed to load variables. No uVars field found.\n");
            end
        end

        % Value changed function: S2DRepeatsEditField
        function S2DRepeatsEditFieldValueChanged(app, event)
            app.updateRepeatsSpinnerLimits();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.DirectoryEditField.Value = uigetdir('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements');
        end

        % Selection change function: MeasMethod
        function MeasMethodSelectionChanged(app, event)
            selectedTab = app.MeasMethod.SelectedTab;
            switch(selectedTab.Title)
                case 'Consistency'
                    app.ConsistencyPlotTab.Visible = true; 
                    app.ScanPlotTab.Visible = false;
                    app.S1DPlotTab.Visible = false;
                    app.MeasurementNameEditField.Value = 'Consistency';
                case 'Scan 2D'
                    app.ConsistencyPlotTab.Visible = false; 
                    app.ScanPlotTab.Visible = true;
                    app.S1DPlotTab.Visible = false;
                    app.MeasurementNameEditField.Value = '2DScan';
                case 'Scan 1D'
                    app.ConsistencyPlotTab.Visible = false; 
                    app.ScanPlotTab.Visible = false;
                    app.S1DPlotTab.Visible = true;
                    app.MeasurementNameEditField.Value = 'Scan1D';
                case 'Single Measurement'
                    app.MeasurementNameEditField.Value = 'AcoustoOptics';
            end
        end

        % Button pushed function: RunButtonConsistency
        function RunButtonConsistencyPushed(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
            app.updatePlotZSpinner();
            app.uVarsConsisUpdate();
            
            
            if app.initConnsistency
                instrreset
                app.consistency.init();
                app.initConnsistency = false;
            elseif app.consStagesRecon
                instrreset % might be a problem with AFG
                app.consistency.restartStages();
            end
            app.s2dStagesRecon = true;
            app.consStagesRecon = false;
            app.consistency.startScan(app.uVarsConsistency);
        end

        % Value changed function: FrameToPlotSpinner
        function FrameToPlotSpinnerValueChanged(app, event)
            app.updateConsFrameLabel();
            app.updateNavFramePlots();
        end

        % Callback function
        function ZcoordinateSpinnerValueChanged(app, event)
           disp("Error - ZCoordinateSpinnerValueChanged function calles")
        end

        % Value changed function: SettoplotSpinner
        function SettoplotSpinnerValueChanged(app, event)
            app.updateNavSetPlots();
        end

        % Callback function
        function ZCoordinateSpinnerValueChanged(app, event)
           disp("Error - ZCoordinateSpinnerValueChanged function calles")
        end

        % Selection change function: GlobalParameters
        function GlobalParametersSelectionChanged(app, event)
            selectedTab = app.GlobalParameters.SelectedTab;
            if strcmp(selectedTab.Title, 'Problem Vars')
                app.collectAlgoVars()
                app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
                app.setAlgoValuesTable();
            end
        end

        % Callback function
        function InButtonPushed(app, event)
            zoom(ax, 'on')
        end

        % Callback function
        function ZoomOutButtonPushed(app, event)
            zoom(ax, 'out')
        end

        % Callback function
        function setUpperLimitsButtonPushed(app, event)
        if strcmp(app.acoustoOpticsPlotsTab.SelectedTab, 'Time Table')
                return
        end
        ax = app.acoustoOpticsPlotsTab.SelectedTab.Children;
        if strcmp(ax.Type, 'axes')
            xlim(ax, [app.StartLimitUpper.Value, app.EndLimitUpper.Value]);
        end
        end

        % Callback function
        function setLowerLimitsButtonPushed(app, event)
            ax = app.SingleMeasurementPlots_2.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, [app.StartLimitLower.Value, app.EndLimitLower.Value]);
            end
        end

        % Callback function
        function resetUpperAxLimitsButtonPushed(app, event)
            if strcmp(app.acoustoOpticsPlotsTab.SelectedTab, 'Time Table')
                return
            end
            ax = app.acoustoOpticsPlotsTab.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, 'auto');
                ylim(ax, 'auto');
            end
        end

        % Callback function
        function resetLowerAxLimitsButtonPushed(app, event)
            if app.ScanPlotTab.Visible
                panel = app.ScanPlotTab;
            elseif app.ConsistencyPlotTab.Visible
                panel = app.ConsistencyPlotTab;
            else
                disp("No visible plot")
                return
            end
            ax = panel.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, 'auto');
                ylim(ax, 'auto');
            end
        end

        % Value changed function: NumberOfChannelsEditField
        function NumberOfChannelsEditFieldValueChanged(app, event)
            updateChannelPlotSpinner()
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
            app.updatePlotZSpinner();
        end

        % Value changed function: SineFrequencyEditField
        function SineFrequencyEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: SinePeriodsEditField
        function SinePeriodsEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: TrainFrequencyEditField
        function TrainFrequencyEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner(); 
        end

        % Value changed function: SpeedinMediumEditField
        function SpeedinMediumEditFieldValueChanged(app, event)
            app.algoData.curRes = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: ZCoorConsSpinner
        function ZCoorConsSpinnerValueChanged(app, event)
            app.updateConsZLabel();
            app.updateConsChAndPos();
        end

        % Value changing function: ZCoorConsSpinner
        function ZCoorConsSpinnerValueChanging(app, event)
            app.updateConsZLabel();
            app.updateConsChAndPos();
        end

        % Value changed function: QuantAOSpinner
        function QuantAOSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: PlotChannelSpinner
        function ChannelAOSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: FreqEnviromentEditField
        function FreqEnviromentAOEditFieldValueChanged(app, event)
            app.updateAOFigs();
        end

        % Callback function
        function ImportResultsButtonPushed(app, event)
            [path] = uigetdir('D:\Results\');
            h = waitbar(0,'Loading Results. Please wait...');
            data = load([path, '\Results']);
            waitbar(.5,h,'Loading Variables. Please wait');
            vars = load([path, '\Vars']);
            waitbar(1,h,'Done');
            close(h)
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            
            app.updateS2dGeneralData(...
                    vars.curVars.acoustoOptics.algoVars.len.zVecUSRes,...
                    vars.curVars.stages.xVec,...
                    vars.curVars.stages.yVec,...
                    vars.curVars.scan.repeats,...
                    vars.curVars.scan.numOfQuant);
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            notify(app, 'updatePhiRepEvent');
            notify(app, 'updatePhiEvent');
            
        end

        % Button pushed function: CloseIOPortButton
        function CloseIOPortButtonPushed(app, event)
            app.ao.IO.close();
            fprintf("Closing IO\n")
        end

        % Button pushed function: TurnOffAFGButton
        function TurnOffAFGButtonPushed(app, event)
            app.ao.fGen.disableAllOutputs();
        end

        % Value changed function: LimitLiveAOCheckBox
        function LimitLiveAOCheckBoxValueChanged(app, event)
            value = app.LimitLiveAOCheckBox.Value;
            if value
                app.NEditField.Enable = true;
            else
                app.NEditField.Enable = false;
            end
        end

        % Button pushed function: ImportVarsButton
        function ImportVarsToGUIButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements','*.mat');
            vars = load([path,filename]);
            if isfield(vars, 'uVars')
                app.setAOVarsToGUI(vars.uVars); 
            else
                app.setAOVarsToGUI(vars);
            end
        end

        % Button pushed function: ExportVarsButton
        function ExportVarsFromGUIButtonPushed(app, event)
            app.collectAOVars();
            % Saved as field called uVars in order to comply with AO vars saving procedure.
            vars.uVars = app.aoData.uVars;
            vars.uVars.figs = [];
            [filename, path] = uiputfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            save([path, filename], '-struct', 'vars', '-v7.3');
        end

        % Value changed function: MeasureatcurrentposCheckBox
        function MeasureatcurrentposCheckBoxValueChanged(app, event)
            value = app.MeasureatcurrentposCheckBox.Value;
            if value
                app.XpositionEditField_2.Enable = false;
                app.YpositionEditField_2.Enable = false;
            else
                app.XpositionEditField_2.Enable = true;
                app.YpositionEditField_2.Enable = true;
            end
        end

        % Value changed function: SamplingMethodDropDown
        function SamplingMethodDropDownValueChanged(app, event)
            value = app.SamplingMethodDropDown.Value;
            if strcmp(value ,'TS')
                set(app.TimetosampleEditField,'Enable','on');
                set(app.secLabel,'Enable','on');
                set(app.PeriodtoSampleEditField,'Enable','off');
                set(app.Label_2,'Enable','off');
            else
                set(app.TimetosampleEditField,'Enable','off');
                set(app.secLabel,'Enable','off');
                set(app.PeriodtoSampleEditField,'Enable','on');
                set(app.Label_2,'Enable','on');           
            end
        end

        % Button pushed function: S2DExportScanVarsButton
        function S2DExportScanVarsButtonPushed(app, event)
            app.collectS2DVars();
            % Saved as field called uVars in order to comply with AO vars saving procedure.
            vars.uVars = app.s2dData.uVars;
            vars.uVars.figs = [];
            vars.uVars.ao.figs = [];
            [filename, path] = uiputfile('*.mat', 'Save Vars As', 'C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements');
            save([path, filename], '-struct', 'vars', '-v7.3');
        end

        % Button pushed function: S2DImportScanVarsButton
        function S2DImportScanVarsButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements','*.mat');
            vars = load([path,filename]);
            if isfield(vars, 'uVars')
                app.setS2DVarsToGUI(vars.uVars);
                app.setAOVarsToGUI(vars.uVars.ao);
            else
                fprintf("GUI: Failed to load variables. No uVars field found.\n");
            end
        end

        % Callback function
        function S1DScanAxisDropDownValueChanged(app, event)
            switch app.S1DScanAxisDropDown.Value
                case 'X'
                    app.S1DDepthAxisDropDown.Items = {'Y', 'Z'};
                    app.S1DDepthAxisDropDown.Value = 'Z';
                case 'Y'
                    app.S1DDepthAxisDropDown.Items = {'X', 'Z'};
                    app.S1DDepthAxisDropDown.Value = 'Z';
                case 'Z'
                    app.S1DDepthAxisDropDown.Items = {'X', 'Y'};
                    app.S1DDepthAxisDropDown.Value = 'Y';   
            end  
        end

        % Value changed function: S2DFirstAxisDropDown
        function S2DFirstAxisDropDownValueChanged(app, event)
            switch app.S2DFirstAxisDropDown.Value
                case 'X'
                    app.S2DSecondAxisDropDown.Items = {'Y', 'Z'};
                    app.S2DSecondAxisDropDown.Value = 'Z';
                case 'Y'
                    app.S2DSecondAxisDropDown.Items = {'X', 'Z'};
                    app.S2DSecondAxisDropDown.Value = 'Z';
                case 'Z'
                    app.S2DSecondAxisDropDown.Items = {'X', 'Y'};
                    app.S2DSecondAxisDropDown.Value = 'Y';   
            end
            
        end

        % Value changed function: Perform2DscanCheckBox
        function Perform2DscanCheckBoxValueChanged(app, event)
            value = app.Perform2DscanCheckBox.Value;
            if value
                app.S2DSecondStrideEditField.Enable = false;
                app.S2DSecondEndEditField.Enable = false;        
            else 
                app.S2DSecondStrideEditField.Enable = true;
                app.S2DSecondEndEditField.Enable = true; 
            end
        end

        % Value changed function: 
        % NormalizeColorstoDisplayedPlaneCheckBox
        function NormalizeColorstoDisplayedPlaneCheckBoxValueChanged(app, event)
            value = app.NormalizeColorstoDisplayedPlaneCheckBox.Value;
            app.s2dGraphics.setColorsScale(value);
            app.updateS2DNavigator();
        end

        % Value changed function: DepthAxisDropDown
        function DepthAxisDropDownValueChanged(app, event)
            value = app.DepthAxisDropDown.Value;
            switch value
                case 'X'
                    app.stage1Label.Text = 'Y';
                    app.stage2Label.Text = 'Z';
                    app.stagesData.stage1Ax = 'Y';
                    app.stagesData.stage2Ax = 'Z';
                case 'Y'
                    app.stage1Label.Text = 'X';
                    app.stage2Label.Text = 'Z';
                    app.stagesData.stage1Ax = 'X';
                    app.stagesData.stage2Ax = 'Z';
                case 'Z'
                    app.stage1Label.Text = 'X';
                    app.stage2Label.Text = 'Y';
                    app.stagesData.stage1Ax = 'X';
                    app.stagesData.stage2Ax = 'Y';
            end
            app.stage1IDDropDown.Value = 'Not Assigned';
            app.stage2IDDropDown.Value = 'Not Assigned';
            app.stagesData.stage1Id = 0;
            app.stagesData.stage2Id = 0;
            app.stagesData.assigned = false;
            app.SetStagesGeometryButton.Enable = true;
        end

        % Button pushed function: SetStagesGeometryButton
        function SetStagesGeometryButtonPushed(app, event)
            if ~app.stagesData.stage1Id || ~app.stagesData.stage2Id
                fprintf("Can't Assign arbitrary stage");
                return
            end
            
            ax  = [app.stagesData.stage1Ax, app.stagesData.stage2Ax];
            ids = [app.stagesData.stage1Id, app.stagesData.stage2Id];

            app.aoStages.assignStagesAxes(ax, ids)
            app.stagesData.assigned = true;
            app.SetStagesGeometryButton.Enable = false;
        end

        % Value changed function: stage1IDDropDown
        function stage1IDDropDownValueChanged(app, event)
            value = app.stage1IDDropDown.Value;
            if strcmp(value, 'Not Assigned')
                app.stagesData.stage1Id = 0;
            else
                app.stagesData.stage1Id = str2double(value);
            end
            app.stagesData.assigned = false;
            app.SetStagesGeometryButton.Enable = true;
        end

        % Value changed function: stage2IDDropDown
        function stage2IDDropDownValueChanged(app, event)
            value = app.stage2IDDropDown.Value;
            if strcmp(value, 'Not Assigned')
                app.stagesData.stage2Id = 0;
            else
                app.stagesData.stage2Id = str2double(value);
            end
            app.stagesData.assigned = false;
            app.SetStagesGeometryButton.Enable = true;
        end

        % Button pushed function: TestAssignmentButton
        function TestAssignmentButtonPushed(app, event)
            app.TestLableLabel.Visible = true;
            if ~app.stagesData.stage1Id
                app.TestLableLabel.Text = sprintf("Stage %s is N/A.", app.stagesData.stage1Ax);
            else
                app.TestLableLabel.Text = sprintf("Stage %s is moving.", app.stagesData.stage1Ax);
                app.aoStages.moveStagesForIdentification(app.stagesData.stage1Id);
            end
            
            if ~app.stagesData.stage2Id
                app.TestLableLabel.Text = sprintf("Stage %s is N/A.", app.stagesData.stage2Ax);
            else
                app.TestLableLabel.Text = sprintf("Stage %s is moving.", app.stagesData.stage2Ax);
                app.aoStages.moveStagesForIdentification(app.stagesData.stage2Id);
            end
            app.TestLableLabel.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadCondifurationsMenu = uimenu(app.FileMenu);
            app.LoadCondifurationsMenu.Text = 'Load Condifurations';
            app.SaveConfigurationsMenu = uimenu(app.FileMenu);
            app.SaveConfigurationsMenu.Text = 'Save Configurations';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.DebugMenu = uimenu(app.ToolsMenu);
            app.DebugMenu.Text = 'Debug';
            app.DeactivatePeripheralsMenu = uimenu(app.DebugMenu);
            app.DeactivatePeripheralsMenu.Text = 'Deactivate Peripherals ';
            app.ExtraFiguresMenu = uimenu(app.DebugMenu);
            app.ExtraFiguresMenu.Text = 'Extra Figures';
            app.PMTSaturationInspector = uimenu(app.ToolsMenu);
            app.PMTSaturationInspector.MenuSelectedFcn = createCallbackFcn(app, @PMTSaturationInspectorMenuSelected, true);
            app.PMTSaturationInspector.Text = 'PMT Saturation Inspector';
            app.ScanAssistantMenu = uimenu(app.ToolsMenu);
            app.ScanAssistantMenu.MenuSelectedFcn = createCallbackFcn(app, @ScanAssistantMenuSelected, true);
            app.ScanAssistantMenu.Text = 'Scan Assistant';
            app.FindMyinstrumentPortMenu = uimenu(app.ToolsMenu);
            app.FindMyinstrumentPortMenu.Text = 'Find My instrument Port';
            app.InstrumentsMenu = uimenu(app.UIFigure);
            app.InstrumentsMenu.Text = 'Instruments';
            app.StagesMenu = uimenu(app.InstrumentsMenu);
            app.StagesMenu.Text = 'Stages';
            app.ScanningAssistantMenu = uimenu(app.StagesMenu);
            app.ScanningAssistantMenu.Text = 'Scanning Assistant';
            app.AFGMenu = uimenu(app.InstrumentsMenu);
            app.AFGMenu.Text = 'AFG';
            app.OscilloscopeMenu = uimenu(app.InstrumentsMenu);
            app.OscilloscopeMenu.Text = 'Oscilloscope';
            app.ResetInstrumentsCommunicationMenu = uimenu(app.InstrumentsMenu);
            app.ResetInstrumentsCommunicationMenu.Text = 'Reset Instruments Communication';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.AcoustoOpticSystemLabel = uilabel(app.UIFigure);
            app.AcoustoOpticSystemLabel.Text = 'AcoustoOptic System';
            app.ScanPlotTab = uitabgroup(app.UIFigure);
            app.MainAxisTab = uitab(app.ScanPlotTab);
            app.MainAxisTab.Title = 'Main Axis';
            app.axMainAxis = uiaxes(app.MainAxisTab);
            title(app.axMainAxis, 'Title')
            xlabel(app.axMainAxis, 'X')
            ylabel(app.axMainAxis, 'Y')
            app.MainPlaneTab = uitab(app.ScanPlotTab);
            app.MainPlaneTab.Title = 'Main Plain';
            app.axMainPlane = uiaxes(app.MainPlaneTab);
            title(app.axMainPlane, 'Title')
            xlabel(app.axMainPlane, 'X')
            ylabel(app.axMainPlane, 'Y')
            app.NavigatorTab = uitab(app.ScanPlotTab);
            app.NavigatorTab.Title = 'Navigator';
            app.axNav = uiaxes(app.NavigatorTab);
            title(app.axNav, 'Title')
            xlabel(app.axNav, 'X')
            ylabel(app.axNav, 'Y')
            app.MainAxisAveragedTab = uitab(app.ScanPlotTab);
            app.MainAxisAveragedTab.Title = 'Main Axis (Averaged)';
            app.axMainAxisAvg = uiaxes(app.MainAxisAveragedTab);
            title(app.axMainAxisAvg, 'Title')
            xlabel(app.axMainAxisAvg, 'X')
            ylabel(app.axMainAxisAvg, 'Y')
            app.MainPlaneAveragedTab = uitab(app.ScanPlotTab);
            app.MainPlaneAveragedTab.Title = 'Main Plain (Averaged)';
            app.axMainPlaneAvg = uiaxes(app.MainPlaneAveragedTab);
            title(app.axMainPlaneAvg, 'Title')
            xlabel(app.axMainPlaneAvg, 'X')
            ylabel(app.axMainPlaneAvg, 'Y')
            app.NavigatorAveragedTab = uitab(app.ScanPlotTab);
            app.NavigatorAveragedTab.Title = 'Navigator (Averaged)';
            app.axNavAvg = uiaxes(app.NavigatorAveragedTab);
            title(app.axNavAvg, 'Title')
            xlabel(app.axNavAvg, 'X')
            ylabel(app.axNavAvg, 'Y')
            app.SimulationNamePanel = uipanel(app.UIFigure);
            app.SimulationNamePanel.Title = 'Simulation Name';
            app.MeasurementNameEditFieldLabel = uilabel(app.SimulationNamePanel);
            app.MeasurementNameEditFieldLabel.Text = 'Measurement Name';
            app.MeasurementNameEditField = uieditfield(app.SimulationNamePanel, 'text');
            app.MeasurementNameEditField.Value = 'Scan';
            app.DirectoryEditFieldLabel = uilabel(app.SimulationNamePanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.SimulationNamePanel, 'text');
            app.DirectoryEditField.Value = 'C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements';
            app.SaveResultsandVarsCheckBox = uicheckbox(app.SimulationNamePanel);
            app.SaveResultsandVarsCheckBox.Text = 'Save Results and Vars';
            app.SaveFigsCheckBox = uicheckbox(app.SimulationNamePanel);
            app.SaveFigsCheckBox.Text = 'Save Figs';
            app.RawDataCheckBox = uicheckbox(app.SimulationNamePanel);
            app.RawDataCheckBox.Text = 'Raw Data';
            app.NetSignalCheckBox = uicheckbox(app.SimulationNamePanel);
            app.NetSignalCheckBox.Text = 'Net Signal';
            app.DeMultiplexedCheckBox = uicheckbox(app.SimulationNamePanel);
            app.DeMultiplexedCheckBox.Text = 'DeMultiplexed';
            app.ReshapedCheckBox_2 = uicheckbox(app.SimulationNamePanel);
            app.ReshapedCheckBox_2.Text = 'Reshaped';
            app.FFTCheckBox_2 = uicheckbox(app.SimulationNamePanel);
            app.FFTCheckBox_2.Text = 'FFT';
            app.SaveSpecificDataLabel = uilabel(app.SimulationNamePanel);
            app.SaveSpecificDataLabel.Text = 'Save Specific Data:';
            app.PhiChCmplxCheckBox = uicheckbox(app.SimulationNamePanel);
            app.PhiChCmplxCheckBox.Text = 'Phi Ch Cmplx';
            app.Button = uibutton(app.SimulationNamePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.MeasMethod = uitabgroup(app.UIFigure);
            app.MeasMethod.SelectionChangedFcn = createCallbackFcn(app, @MeasMethodSelectionChanged, true);
            app.SingleMeasurementTab = uitab(app.MeasMethod);
            app.SingleMeasurementTab.Title = 'Single Measurement';
            app.RunButtonAO = uibutton(app.SingleMeasurementTab, 'push');
            app.RunButtonAO.ButtonPushedFcn = createCallbackFcn(app, @RunButtonAOPushed, true);
            app.RunButtonAO.Text = 'Run';
            app.RunLiveButton = uibutton(app.SingleMeasurementTab, 'push');
            app.RunLiveButton.ButtonPushedFcn = createCallbackFcn(app, @RunLiveButtonPushed, true);
            app.RunLiveButton.Text = 'Run Live';
            app.StagesPanel_3 = uipanel(app.SingleMeasurementTab);
            app.StagesPanel_3.Title = 'Stages';
            app.mmLabel_17 = uilabel(app.StagesPanel_3);
            app.mmLabel_17.Text = '[mm]';
            app.mmLabel_18 = uilabel(app.StagesPanel_3);
            app.mmLabel_18.Text = '[mm]';
            app.XpositionEditField_2Label = uilabel(app.StagesPanel_3);
            app.XpositionEditField_2Label.Enable = 'off';
            app.XpositionEditField_2Label.Text = 'X position';
            app.XpositionEditField_2 = uieditfield(app.StagesPanel_3, 'numeric');
            app.XpositionEditField_2.Enable = 'off';
            app.XpositionEditField_2.Value = 124;
            app.YpositionEditField_2Label = uilabel(app.StagesPanel_3);
            app.YpositionEditField_2Label.Enable = 'off';
            app.YpositionEditField_2Label.Text = 'Y position';
            app.YpositionEditField_2 = uieditfield(app.StagesPanel_3, 'numeric');
            app.YpositionEditField_2.Enable = 'off';
            app.YpositionEditField_2.Value = 90;
            app.MeasureatcurrentposCheckBox = uicheckbox(app.StagesPanel_3);
            app.MeasureatcurrentposCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasureatcurrentposCheckBoxValueChanged, true);
            app.MeasureatcurrentposCheckBox.Text = 'Measure at current pos';
            app.MeasureatcurrentposCheckBox.Value = true;
            app.LiveAcoustoOpticsPanel = uipanel(app.SingleMeasurementTab);
            app.LiveAcoustoOpticsPanel.Title = 'Live Acousto Optics';
            app.LimitLiveAOCheckBox = uicheckbox(app.LiveAcoustoOpticsPanel);
            app.LimitLiveAOCheckBox.ValueChangedFcn = createCallbackFcn(app, @LimitLiveAOCheckBoxValueChanged, true);
            app.LimitLiveAOCheckBox.Text = 'LimitLiveAO';
            app.LimitLiveAOCheckBox.Value = true;
            app.NEditFieldLabel = uilabel(app.LiveAcoustoOpticsPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.LiveAcoustoOpticsPanel, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.Value = 1;
            app.ImportsavedResultsPanel = uipanel(app.SingleMeasurementTab);
            app.ImportsavedResultsPanel.Title = 'Import saved Results';
            app.DataEditFieldLabel = uilabel(app.ImportsavedResultsPanel);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.ImportsavedResultsPanel, 'text');
            app.DataEditField.Editable = 'off';
            app.analysisDataPathButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.analysisDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisDataPathButtonPushed, true);
            app.analysisDataPathButton.Text = '...';
            app.AnalyzeOrigButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.AnalyzeOrigButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeOrigButtonPushed, true);
            app.AnalyzeOrigButton.Enable = 'off';
            app.AnalyzeOrigButton.Tooltip = {'Analyze loaded data with original variables.'};
            app.AnalyzeOrigButton.Text = 'Analyze Orig';
            app.VarsEditFieldLabel = uilabel(app.ImportsavedResultsPanel);
            app.VarsEditFieldLabel.Text = 'Vars';
            app.VarsEditField = uieditfield(app.ImportsavedResultsPanel, 'text');
            app.VarsEditField.Editable = 'off';
            app.analysisVarsPathButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.analysisVarsPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisVarsPathButtonPushed, true);
            app.analysisVarsPathButton.Text = '...';
            app.ImportButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImporAOResultstButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.AnalyizeNewButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.AnalyizeNewButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyizeNewButtonPushed, true);
            app.AnalyizeNewButton.Enable = 'off';
            app.AnalyizeNewButton.Tooltip = {'Analyse with variables appear in this GUI'};
            app.AnalyizeNewButton.Text = 'Analyize New';
            app.UseVirtualDataCheckBox = uicheckbox(app.ImportsavedResultsPanel);
            app.UseVirtualDataCheckBox.Text = 'Use Virtual Data';
            app.ImportVarsButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ImportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportVarsToGUIButtonPushed, true);
            app.ImportVarsButton.Text = 'Import Vars';
            app.ExportVarsButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ExportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVarsFromGUIButtonPushed, true);
            app.ExportVarsButton.Text = 'Export Vars';
            app.Scan3DTab = uitab(app.MeasMethod);
            app.Scan3DTab.Title = 'Scan 3D';
            app.StagesPanel = uipanel(app.Scan3DTab);
            app.StagesPanel.Title = 'Stages';
            app.StagesCalibToolButton = uibutton(app.StagesPanel, 'push');
            app.StagesCalibToolButton.ButtonPushedFcn = createCallbackFcn(app, @StagesCalibToolButtonPushed, true);
            app.StagesCalibToolButton.Text = 'Stages Calib Tool';
            app.mmLabel_4 = uilabel(app.StagesPanel);
            app.mmLabel_4.Text = '[mm]';
            app.mmLabel_5 = uilabel(app.StagesPanel);
            app.mmLabel_5.Text = '[mm]';
            app.mmLabel_6 = uilabel(app.StagesPanel);
            app.mmLabel_6.Text = '[mm]';
            app.mmLabel_7 = uilabel(app.StagesPanel);
            app.mmLabel_7.Text = '[mm]';
            app.mmLabel_8 = uilabel(app.StagesPanel);
            app.mmLabel_8.Text = '[mm]';
            app.mmLabel_9 = uilabel(app.StagesPanel);
            app.mmLabel_9.Text = '[mm]';
            app.StartEditFieldLabel = uilabel(app.StagesPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.S2DFirstStartEditField = uieditfield(app.StagesPanel, 'numeric');
            app.S2DFirstStartEditField.Value = 5;
            app.EndEditFieldLabel = uilabel(app.StagesPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.S2DFirstEndEditField = uieditfield(app.StagesPanel, 'numeric');
            app.S2DFirstEndEditField.Value = 20;
            app.StartEditField_2Label = uilabel(app.StagesPanel);
            app.StartEditField_2Label.Text = 'Start';
            app.S2DSecondStartEditField = uieditfield(app.StagesPanel, 'numeric');
            app.S2DSecondStartEditField.Value = 10;
            app.EndEditField_2Label = uilabel(app.StagesPanel);
            app.EndEditField_2Label.Text = 'End';
            app.S2DSecondEndEditField = uieditfield(app.StagesPanel, 'numeric');
            app.S2DSecondEndEditField.Value = 15;
            app.StrideEditFieldLabel = uilabel(app.StagesPanel);
            app.StrideEditFieldLabel.Text = 'Stride';
            app.S2DFirstStrideEditField = uieditfield(app.StagesPanel, 'numeric');
            app.S2DFirstStrideEditField.Value = 1;
            app.StrideEditField_2Label = uilabel(app.StagesPanel);
            app.StrideEditField_2Label.Text = 'Stride';
            app.S2DSecondStrideEditField = uieditfield(app.StagesPanel, 'numeric');
            app.S2DSecondStrideEditField.Value = 1;
            app.AxisDropDownLabel = uilabel(app.StagesPanel);
            app.AxisDropDownLabel.Text = 'Axis';
            app.S2DFirstAxisDropDown = uidropdown(app.StagesPanel);
            app.S2DFirstAxisDropDown.Items = {'Y', 'X'};
            app.S2DFirstAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @S2DFirstAxisDropDownValueChanged, true);
            app.S2DFirstAxisDropDown.Value = 'Y';
            app.S2DImportLimitsfromfileButton = uibutton(app.StagesPanel, 'push');
            app.S2DImportLimitsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @S2DImportLimitsfromfileButtonPushed, true);
            app.S2DImportLimitsfromfileButton.Text = 'Import Limits from file';
            app.FirstScanAxisLabel = uilabel(app.StagesPanel);
            app.FirstScanAxisLabel.Text = 'First Scan Axis:';
            app.SecondScanAxisLabel = uilabel(app.StagesPanel);
            app.SecondScanAxisLabel.Text = 'Second Scan Axis:';
            app.AxisDropDown_2Label = uilabel(app.StagesPanel);
            app.AxisDropDown_2Label.Text = 'Axis';
            app.S2DSecondAxisDropDown = uidropdown(app.StagesPanel);
            app.S2DSecondAxisDropDown.Items = {'X', 'Z'};
            app.S2DSecondAxisDropDown.Value = 'X';
            app.Perform2DscanCheckBox = uicheckbox(app.StagesPanel);
            app.Perform2DscanCheckBox.ValueChangedFcn = createCallbackFcn(app, @Perform2DscanCheckBoxValueChanged, true);
            app.Perform2DscanCheckBox.Text = 'Perform 2D scan';
            app.S2DRunButton = uibutton(app.Scan3DTab, 'push');
            app.S2DRunButton.ButtonPushedFcn = createCallbackFcn(app, @S2DRunButtonPushed, true);
            app.S2DRunButton.Text = 'Run';
            app.RepeatsPanel = uipanel(app.Scan3DTab);
            app.RepeatsPanel.Title = 'Repeats';
            app.RepeatsEditFieldLabel = uilabel(app.RepeatsPanel);
            app.RepeatsEditFieldLabel.Text = 'Repeats';
            app.S2DRepeatsEditField = uieditfield(app.RepeatsPanel, 'numeric');
            app.S2DRepeatsEditField.ValueChangedFcn = createCallbackFcn(app, @S2DRepeatsEditFieldValueChanged, true);
            app.S2DRepeatsEditField.Value = 1;
            app.S2DImportResultsButton = uibutton(app.Scan3DTab, 'push');
            app.S2DImportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportS2DscanResultsButtonPushed, true);
            app.S2DImportResultsButton.Text = 'Import Results';
            app.S2DExportScanVarsButton = uibutton(app.Scan3DTab, 'push');
            app.S2DExportScanVarsButton.ButtonPushedFcn = createCallbackFcn(app, @S2DExportScanVarsButtonPushed, true);
            app.S2DExportScanVarsButton.Text = 'Export Scan Vars';
            app.S2DImportScanVarsButton = uibutton(app.Scan3DTab, 'push');
            app.S2DImportScanVarsButton.ButtonPushedFcn = createCallbackFcn(app, @S2DImportScanVarsButtonPushed, true);
            app.S2DImportScanVarsButton.Text = 'Import Scan Vars';
            app.ConsistencyTab = uitab(app.MeasMethod);
            app.ConsistencyTab.Title = 'Consistency';
            app.RunButtonConsistency = uibutton(app.ConsistencyTab, 'push');
            app.RunButtonConsistency.ButtonPushedFcn = createCallbackFcn(app, @RunButtonConsistencyPushed, true);
            app.RunButtonConsistency.Text = 'Run';
            app.StagesPanel_2 = uipanel(app.ConsistencyTab);
            app.StagesPanel_2.Title = 'Stages';
            app.mmLabel_10 = uilabel(app.StagesPanel_2);
            app.mmLabel_10.Text = '[mm]';
            app.mmLabel_11 = uilabel(app.StagesPanel_2);
            app.mmLabel_11.Text = '[mm]';
            app.XpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.XpositionEditFieldLabel.Text = 'X position';
            app.XpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.XpositionEditField.Value = 124;
            app.YpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.YpositionEditFieldLabel.Text = 'Y position';
            app.YpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.YpositionEditField.Value = 90;
            app.TimeFramePropertiesPanel = uipanel(app.ConsistencyTab);
            app.TimeFramePropertiesPanel.Title = 'Time Frame Properties';
            app.sLabel = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel.Text = '[s]';
            app.sLabel_2 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_2.Text = '[s]';
            app.sLabel_3 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_3.Text = '[s]';
            app.sLabel_4 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_4.Text = '[s]';
            app.MaxTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MaxTimeFrameDurationEditFieldLabel.Text = 'Max Time Frame Duration';
            app.MaxTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MaxTimeFrameDurationEditField.Value = 8;
            app.MinTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MinTimeFrameDurationEditFieldLabel.Text = 'Min Time Frame Duration';
            app.MinTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MinTimeFrameDurationEditField.Value = 2;
            app.TimeStrideEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.TimeStrideEditFieldLabel.Text = 'Time Stride';
            app.TimeStrideEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.TimeStrideEditField.Value = 2;
            app.SetsperTimeFrameEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.SetsperTimeFrameEditFieldLabel.Text = 'Sets per Time Frame';
            app.SetsperTimeFrameEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.SetsperTimeFrameEditField.Value = 20;
            app.ImportResultsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ImportResultsButton_2.Enable = 'off';
            app.ImportResultsButton_2.Text = 'Import Results';
            app.ExportScanVarsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ExportScanVarsButton_2.Text = 'Export Scan Vars';
            app.ImportScanVarsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ImportScanVarsButton_2.Text = 'Import Scan Vars';
            app.FiguresPanel_2 = uipanel(app.ConsistencyTab);
            app.FiguresPanel_2.Title = 'Figures';
            app.FrameToPlotSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.FrameToPlotSpinnerLabel.Text = 'Frame To Plot';
            app.FrameToPlotSpinner = uispinner(app.FiguresPanel_2);
            app.FrameToPlotSpinner.Limits = [1 2];
            app.FrameToPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameToPlotSpinnerValueChanged, true);
            app.FrameToPlotSpinner.Value = 1;
            app.SettoplotSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.SettoplotSpinnerLabel.Text = 'Set to plot';
            app.SettoplotSpinner = uispinner(app.FiguresPanel_2);
            app.SettoplotSpinner.Limits = [1 2];
            app.SettoplotSpinner.ValueChangedFcn = createCallbackFcn(app, @SettoplotSpinnerValueChanged, true);
            app.SettoplotSpinner.Value = 1;
            app.ZCoorSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.ZCoorSpinnerLabel.Text = 'Z Coor';
            app.ZCoorConsSpinner = uispinner(app.FiguresPanel_2);
            app.ZCoorConsSpinner.ValueChangingFcn = createCallbackFcn(app, @ZCoorConsSpinnerValueChanging, true);
            app.ZCoorConsSpinner.ValueChangedFcn = createCallbackFcn(app, @ZCoorConsSpinnerValueChanged, true);
            app.ZCoorConsSpinner.Value = 1;
            app.sLabel_6 = uilabel(app.FiguresPanel_2);
            app.sLabel_6.Text = '0 [s]';
            app.mmLabel_15 = uilabel(app.FiguresPanel_2);
            app.mmLabel_15.Text = '00.00[mm]';
            app.ConsistencyPlotTab = uitabgroup(app.UIFigure);
            app.ConsistencyPlotTab.Visible = 'off';
            app.AllFramesStatisticsTab = uitab(app.ConsistencyPlotTab);
            app.AllFramesStatisticsTab.Title = 'All Frames Statistics';
            app.axAllFrames = uiaxes(app.AllFramesStatisticsTab);
            title(app.axAllFrames, 'Time Frame Length vs. Intensity')
            xlabel(app.axAllFrames, 'Time Frame Length [s]')
            ylabel(app.axAllFrames, 'Intensity')
            app.CurrentFrameTab = uitab(app.ConsistencyPlotTab);
            app.CurrentFrameTab.Title = 'Current Frame';
            app.axCurFrame = uiaxes(app.CurrentFrameTab);
            title(app.axCurFrame, 'Current Frame''s Set')
            xlabel(app.axCurFrame, 'X')
            ylabel(app.axCurFrame, 'Y')
            app.CurrentSetQuantsTab = uitab(app.ConsistencyPlotTab);
            app.CurrentSetQuantsTab.Title = 'Current Set (Quants)';
            app.axCurSet = uiaxes(app.CurrentSetQuantsTab);
            title(app.axCurSet, 'Current Set Qunata')
            xlabel(app.axCurSet, 'X')
            ylabel(app.axCurSet, 'Y')
            app.FramesNavigatorTab = uitab(app.ConsistencyPlotTab);
            app.FramesNavigatorTab.Title = 'Frames Navigator';
            app.axNavFrame = uiaxes(app.FramesNavigatorTab);
            title(app.axNavFrame, 'Frame Navigator')
            xlabel(app.axNavFrame, 'Set Number')
            ylabel(app.axNavFrame, 'Fluence Rate')
            app.SetsNavigatorQuantsTab = uitab(app.ConsistencyPlotTab);
            app.SetsNavigatorQuantsTab.Title = 'Sets Navigator (Quants)';
            app.axNavSet = uiaxes(app.SetsNavigatorQuantsTab);
            title(app.axNavSet, 'Set Navigator')
            xlabel(app.axNavSet, 'Set Instance')
            ylabel(app.axNavSet, 'Y')
            app.GlobalParameters = uitabgroup(app.UIFigure);
            app.GlobalParameters.SelectionChangedFcn = createCallbackFcn(app, @GlobalParametersSelectionChanged, true);
            app.SignalExcitationTab = uitab(app.GlobalParameters);
            app.SignalExcitationTab.Title = 'Signal Excitation';
            app.UseQuantCheckBox = uicheckbox(app.SignalExcitationTab);
            app.UseQuantCheckBox.Text = 'Use Quant';
            app.SamplingFrequencyextClkPanel = uipanel(app.SignalExcitationTab);
            app.SamplingFrequencyextClkPanel.Title = 'Sampling Frequency (extClk)';
            app.MHzLabel_2 = uilabel(app.SamplingFrequencyextClkPanel);
            app.MHzLabel_2.Text = '[MHz]';
            app.VLabel_10 = uilabel(app.SamplingFrequencyextClkPanel);
            app.VLabel_10.Text = '[V]';
            app.VLabel_11 = uilabel(app.SamplingFrequencyextClkPanel);
            app.VLabel_11.Text = '[V]';
            app.Label_12 = uilabel(app.SamplingFrequencyextClkPanel);
            app.Label_12.Text = '[%]';
            app.FrequencyEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 5;
            app.AmplitudeEditField_4Label = uilabel(app.SamplingFrequencyextClkPanel);
            app.AmplitudeEditField_4Label.Text = 'Amplitude';
            app.AmplitudeEditField_4 = uieditfield(app.SamplingFrequencyextClkPanel, 'text');
            app.AmplitudeEditField_4.Value = '1';
            app.OffsetEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'text');
            app.OffsetEditField.Value = '0';
            app.DutyCycleEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'numeric');
            app.DutyCycleEditField.Value = 50;
            app.UltrasoundSignalPropertiesPanel = uipanel(app.SignalExcitationTab);
            app.UltrasoundSignalPropertiesPanel.Title = 'Ultrasound Signal Properties';
            app.MHzLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.MHzLabel.Text = '[MHz]';
            app.Label_13 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.Label_13.Text = '[#]';
            app.KHzLabel_3 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.KHzLabel_3.Text = '[KHz]';
            app.SineFrequencyEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SineFrequencyEditFieldLabel.Text = 'Sine Frequency';
            app.SineFrequencyEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SineFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SineFrequencyEditFieldValueChanged, true);
            app.SineFrequencyEditField.Value = 1.25;
            app.TrainFrequencyEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.TrainFrequencyEditFieldLabel.Text = 'Train Frequency';
            app.TrainFrequencyEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.TrainFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @TrainFrequencyEditFieldValueChanged, true);
            app.TrainFrequencyEditField.Value = 16.5;
            app.SinePeriodsEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SinePeriodsEditFieldLabel.Text = 'Sine Periods';
            app.SinePeriodsEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SinePeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @SinePeriodsEditFieldValueChanged, true);
            app.SinePeriodsEditField.Value = 4;
            app.OptimizetrainfrequencyCheckBox = uicheckbox(app.UltrasoundSignalPropertiesPanel);
            app.OptimizetrainfrequencyCheckBox.Text = 'Optimize train frequency';
            app.GeometryPanel = uipanel(app.SignalExcitationTab);
            app.GeometryPanel.Title = 'Geometry';
            app.PhantomdepthEditFieldLabel = uilabel(app.GeometryPanel);
            app.PhantomdepthEditFieldLabel.Text = 'Phantom depth';
            app.PhantomdepthEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.PhantomdepthEditField.Value = 4.6;
            app.TransducerheightEditFieldLabel = uilabel(app.GeometryPanel);
            app.TransducerheightEditFieldLabel.Text = 'Transducer height';
            app.TransducerheightEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.TransducerheightEditField.Value = 11.2;
            app.TransducerdistanceLabel = uilabel(app.GeometryPanel);
            app.TransducerdistanceLabel.Text = 'Transducer distance';
            app.TransducerdistanceEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.TransducerdistanceEditField.Value = 7.3;
            app.msLabel = uilabel(app.GeometryPanel);
            app.msLabel.Text = '[m/s]';
            app.SpeedinMediumEditFieldLabel = uilabel(app.GeometryPanel);
            app.SpeedinMediumEditFieldLabel.Text = 'Speed in Medium';
            app.SpeedinMediumEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.SpeedinMediumEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedinMediumEditFieldValueChanged, true);
            app.SpeedinMediumEditField.Value = 1545;
            app.cmLabel_3 = uilabel(app.GeometryPanel);
            app.cmLabel_3.Text = '[cm]';
            app.cmLabel_2 = uilabel(app.GeometryPanel);
            app.cmLabel_2.Text = '[cm]';
            app.cmLabel = uilabel(app.GeometryPanel);
            app.cmLabel.Text = '[cm]';
            app.GeneralPanel = uipanel(app.SignalExcitationTab);
            app.GeneralPanel.Title = 'General';
            app.TimetosampleEditFieldLabel = uilabel(app.GeneralPanel);
            app.TimetosampleEditFieldLabel.Text = 'Time to sample';
            app.TimetosampleEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.TimetosampleEditField.Value = 2;
            app.UseTimeQuantCheckBox = uicheckbox(app.GeneralPanel);
            app.UseTimeQuantCheckBox.Text = 'Use Time Quant';
            app.UseTimeQuantCheckBox.Value = true;
            app.QuantTimeEditFieldLabel = uilabel(app.GeneralPanel);
            app.QuantTimeEditFieldLabel.Text = 'Quant Time';
            app.QuantTimeEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.QuantTimeEditField.Value = 0.002;
            app.FastAnalysisCheckBox = uicheckbox(app.GeneralPanel);
            app.FastAnalysisCheckBox.Text = 'Fast Analysis';
            app.UseGPUCheckBox = uicheckbox(app.GeneralPanel);
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.CalcOnlyUSFrequencyLabel = uilabel(app.GeneralPanel);
            app.CalcOnlyUSFrequencyLabel.Text = '(Calc Only US Frequency)';
            app.secLabel = uilabel(app.GeneralPanel);
            app.secLabel.Text = '[sec]';
            app.secLabel_2 = uilabel(app.GeneralPanel);
            app.secLabel_2.Text = '[sec]';
            app.UseHadamardCodeCheckBox = uicheckbox(app.GeneralPanel);
            app.UseHadamardCodeCheckBox.Text = 'Use Hadamard Code';
            app.PeripheralsTab = uitab(app.GlobalParameters);
            app.PeripheralsTab.Title = 'Peripherals';
            app.FunctionGeneratorPanel = uipanel(app.PeripheralsTab);
            app.FunctionGeneratorPanel.Title = 'Function Generator';
            app.SclkEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.SclkEditFieldLabel.Text = 'Sclk';
            app.SclkEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.SclkEditField.Value = 100;
            app.DAQFilterDropDownLabel = uilabel(app.FunctionGeneratorPanel);
            app.DAQFilterDropDownLabel.Text = 'DAQ Filter';
            app.DAQFilterDropDown = uidropdown(app.FunctionGeneratorPanel);
            app.DAQFilterDropDown.Items = {'25MHz', '50MHz'};
            app.DAQFilterDropDown.Value = '50MHz';
            app.TriggerWidthEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.TriggerWidthEditFieldLabel.Text = 'Trigger Width';
            app.TriggerWidthEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.TriggerWidthEditField.Value = 500;
            app.AmplitudeEditField_3Label = uilabel(app.FunctionGeneratorPanel);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField_3 = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.AmplitudeEditField_3.Value = 1;
            app.BiasEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.BiasEditFieldLabel.Text = 'Bias';
            app.BiasEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.MSsLabel = uilabel(app.FunctionGeneratorPanel);
            app.MSsLabel.Text = '[MS/s]';
            app.SLabel = uilabel(app.FunctionGeneratorPanel);
            app.SLabel.Text = '[S]';
            app.VLabel_8 = uilabel(app.FunctionGeneratorPanel);
            app.VLabel_8.Text = '[V]';
            app.VLabel_9 = uilabel(app.FunctionGeneratorPanel);
            app.VLabel_9.Text = '[V]';
            app.DigitizerPanel = uipanel(app.PeripheralsTab);
            app.DigitizerPanel.Title = 'Digitizer';
            app.Label_10 = uilabel(app.DigitizerPanel);
            app.Label_10.Text = '[#]';
            app.NumberOfChannelsEditFieldLabel = uilabel(app.DigitizerPanel);
            app.NumberOfChannelsEditFieldLabel.Text = 'Number Of Channels';
            app.NumberOfChannelsEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.NumberOfChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfChannelsEditFieldValueChanged, true);
            app.NumberOfChannelsEditField.Value = 4;
            app.Label_11 = uilabel(app.DigitizerPanel);
            app.Label_11.Enable = 'off';
            app.Label_11.Text = '[#]';
            app.PeriodtoSampleEditFieldLabel = uilabel(app.DigitizerPanel);
            app.PeriodtoSampleEditFieldLabel.Text = 'Period to Sample';
            app.PeriodtoSampleEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.PeriodtoSampleEditField.Enable = 'off';
            app.PeriodtoSampleEditField.Value = 8192;
            app.VppLabel = uilabel(app.DigitizerPanel);
            app.VppLabel.Text = '[Vpp]';
            app.VoltsRangeEditFieldLabel = uilabel(app.DigitizerPanel);
            app.VoltsRangeEditFieldLabel.Text = 'Volts Range';
            app.VoltsRangeEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.VoltsRangeEditField.Value = 2;
            app.SamplingMethodDropDownLabel = uilabel(app.DigitizerPanel);
            app.SamplingMethodDropDownLabel.Text = 'Sampling Method';
            app.SamplingMethodDropDown = uidropdown(app.DigitizerPanel);
            app.SamplingMethodDropDown.Items = {'NPT', 'TS'};
            app.SamplingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingMethodDropDownValueChanged, true);
            app.SamplingMethodDropDown.Value = 'TS';
            app.LaserPanel = uipanel(app.PeripheralsTab);
            app.LaserPanel.Title = 'Laser';
            app.nmLabel = uilabel(app.LaserPanel);
            app.nmLabel.Text = '[nm]';
            app.mWLabel = uilabel(app.LaserPanel);
            app.mWLabel.Text = '[mW]';
            app.KHzLabel_4 = uilabel(app.LaserPanel);
            app.KHzLabel_4.Text = '[KHz]';
            app.sLabel_5 = uilabel(app.LaserPanel);
            app.sLabel_5.Text = '[s]';
            app.WavelengthEditFieldLabel = uilabel(app.LaserPanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.LaserPanel, 'numeric');
            app.WavelengthEditField.Value = 785;
            app.PowerEditFieldLabel = uilabel(app.LaserPanel);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.LaserPanel, 'numeric');
            app.PowerEditField.Value = 50;
            app.LineWidthEditFieldLabel = uilabel(app.LaserPanel);
            app.LineWidthEditFieldLabel.Text = 'LineWidth';
            app.LineWidthEditField = uieditfield(app.LaserPanel, 'numeric');
            app.LineWidthEditField.Value = 5000;
            app.SpeckleDurationEditFieldLabel = uilabel(app.LaserPanel);
            app.SpeckleDurationEditFieldLabel.Text = 'Speckle Duration';
            app.SpeckleDurationEditField = uieditfield(app.LaserPanel, 'numeric');
            app.SpeckleDurationEditField.Value = 0.5;
            app.TurnOffAFGButton = uibutton(app.PeripheralsTab, 'push');
            app.TurnOffAFGButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOffAFGButtonPushed, true);
            app.TurnOffAFGButton.Text = 'Turn Off AFG';
            app.CloseIOPortButton = uibutton(app.PeripheralsTab, 'push');
            app.CloseIOPortButton.ButtonPushedFcn = createCallbackFcn(app, @CloseIOPortButtonPushed, true);
            app.CloseIOPortButton.Text = 'Close IO Port';
            app.StagesHomeButton = uibutton(app.PeripheralsTab, 'push');
            app.StagesHomeButton.Text = 'Stages Home';
            app.StagesLayoutInSystemPanel = uipanel(app.PeripheralsTab);
            app.StagesLayoutInSystemPanel.Title = 'Stages Layout In System';
            app.DepthAxisDropDownLabel = uilabel(app.StagesLayoutInSystemPanel);
            app.DepthAxisDropDownLabel.Text = 'Depth Axis';
            app.DepthAxisDropDown = uidropdown(app.StagesLayoutInSystemPanel);
            app.DepthAxisDropDown.Items = {'Z', 'X', 'Y'};
            app.DepthAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @DepthAxisDropDownValueChanged, true);
            app.DepthAxisDropDown.Value = 'Z';
            app.stage1Label = uilabel(app.StagesLayoutInSystemPanel);
            app.stage1Label.Text = 'X';
            app.stage2Label = uilabel(app.StagesLayoutInSystemPanel);
            app.stage2Label.Text = 'Y';
            app.stageIDDropDownLabel = uilabel(app.StagesLayoutInSystemPanel);
            app.stageIDDropDownLabel.Text = 'stage ID';
            app.stage1IDDropDown = uidropdown(app.StagesLayoutInSystemPanel);
            app.stage1IDDropDown.Items = {'1', '2'};
            app.stage1IDDropDown.ValueChangedFcn = createCallbackFcn(app, @stage1IDDropDownValueChanged, true);
            app.stage1IDDropDown.Value = '1';
            app.stageIDDropDown_2Label = uilabel(app.StagesLayoutInSystemPanel);
            app.stageIDDropDown_2Label.Text = 'stage ID';
            app.stage2IDDropDown = uidropdown(app.StagesLayoutInSystemPanel);
            app.stage2IDDropDown.Items = {'1'};
            app.stage2IDDropDown.ValueChangedFcn = createCallbackFcn(app, @stage2IDDropDownValueChanged, true);
            app.stage2IDDropDown.Value = '1';
            app.SetStagesGeometryButton = uibutton(app.StagesLayoutInSystemPanel, 'push');
            app.SetStagesGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @SetStagesGeometryButtonPushed, true);
            app.SetStagesGeometryButton.Text = {'Set Stages '; 'Geometry'};
            app.TestAssignmentButton = uibutton(app.StagesLayoutInSystemPanel, 'push');
            app.TestAssignmentButton.ButtonPushedFcn = createCallbackFcn(app, @TestAssignmentButtonPushed, true);
            app.TestAssignmentButton.Text = 'Test Assignment';
            app.TestLableLabel = uilabel(app.StagesLayoutInSystemPanel);
            app.TestLableLabel.Visible = 'off';
            app.TestLableLabel.Text = 'Test Lable';
            app.ProblemVarsTab = uitab(app.GlobalParameters);
            app.ProblemVarsTab.Title = 'Problem Vars';
            app.TabGroup2 = uitabgroup(app.ProblemVarsTab);
            app.SystemClkTab = uitab(app.TabGroup2);
            app.SystemClkTab.Title = 'System Clk';
            app.UITable_6 = uitable(app.SystemClkTab);
            app.UITable_6.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_6.RowName = {};
            app.USSignalTab = uitab(app.TabGroup2);
            app.USSignalTab.Title = 'US Signal';
            app.UITable_5 = uitable(app.USSignalTab);
            app.UITable_5.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_5.RowName = {};
            app.SamplesTab = uitab(app.TabGroup2);
            app.SamplesTab.Title = 'Samples';
            app.UITable = uitable(app.SamplesTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable.RowName = {};
            app.TimingTab = uitab(app.TabGroup2);
            app.TimingTab.Title = 'Timing';
            app.UITable_3 = uitable(app.TimingTab);
            app.UITable_3.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_3.RowName = {};
            app.DigitizerTab_2 = uitab(app.TabGroup2);
            app.DigitizerTab_2.Title = 'Digitizer';
            app.UITable_4 = uitable(app.DigitizerTab_2);
            app.UITable_4.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_4.RowName = {};
            app.LengthTab = uitab(app.TabGroup2);
            app.LengthTab.Title = 'Length';
            app.UITable_2 = uitable(app.LengthTab);
            app.UITable_2.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_2.RowName = {};
            app.FreqTab = uitab(app.TabGroup2);
            app.FreqTab.Title = 'Freq';
            app.UITable_7 = uitable(app.FreqTab);
            app.UITable_7.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_7.RowName = {};
            app.PlottingTab = uitab(app.GlobalParameters);
            app.PlottingTab.Title = 'Plotting';
            app.AcoustoOpticsPanel = uipanel(app.PlottingTab);
            app.AcoustoOpticsPanel.Title = 'Acousto Optics';
            app.PlotChannelSpinnerLabel = uilabel(app.AcoustoOpticsPanel);
            app.PlotChannelSpinnerLabel.Text = 'Plot Channel';
            app.PlotChannelSpinner = uispinner(app.AcoustoOpticsPanel);
            app.PlotChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelAOSpinnerValueChanged, true);
            app.PlotChannelSpinner.Value = 1;
            app.ZCoorSpinner_3Label = uilabel(app.AcoustoOpticsPanel);
            app.ZCoorSpinner_3Label.Text = 'Z Coor';
            app.AOZIdxSpinner = uispinner(app.AcoustoOpticsPanel);
            app.AOZIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @AOZIdxSpinnerValueChanged, true);
            app.AOZIdxSpinner.Value = 1;
            app.QuantSpinnerLabel = uilabel(app.AcoustoOpticsPanel);
            app.QuantSpinnerLabel.Text = 'Quant';
            app.QuantAOSpinner = uispinner(app.AcoustoOpticsPanel);
            app.QuantAOSpinner.ValueChangedFcn = createCallbackFcn(app, @QuantAOSpinnerValueChanged, true);
            app.QuantAOSpinner.Value = 1;
            app.ExternalClkCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.ExternalClkCheckBox.Text = 'External Clk';
            app.UltrasoundSignalCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.UltrasoundSignalCheckBox.Text = 'Ultrasound Signal';
            app.FullSignalCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.FullSignalCheckBox.Text = 'Full Signal';
            app.MeasuredSamplesCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.MeasuredSamplesCheckBox.Text = 'Measured Samples';
            app.NetSamplesCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.NetSamplesCheckBox.Text = 'Net Samples';
            app.ReshapedCheckBox_3 = uicheckbox(app.AcoustoOpticsPanel);
            app.ReshapedCheckBox_3.Text = 'Reshaped';
            app.FFTCheckBox_3 = uicheckbox(app.AcoustoOpticsPanel);
            app.FFTCheckBox_3.Text = 'FFT';
            app.FFTCheckBox_3.Value = true;
            app.PhiChCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiChCheckBox.Text = 'Phi (Ch)';
            app.PhiChCheckBox.Value = true;
            app.PhiCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiCheckBox.Text = 'Phi';
            app.PhiCheckBox.Value = true;
            app.PhiQuantCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiQuantCheckBox.Enable = 'off';
            app.PhiQuantCheckBox.Text = 'Phi (Quant)';
            app.DemultiplexedCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.DemultiplexedCheckBox.Text = 'Demultiplexed';
            app.FreqEnviromentEditFieldLabel = uilabel(app.AcoustoOpticsPanel);
            app.FreqEnviromentEditFieldLabel.Text = 'Freq Enviroment';
            app.FreqEnviromentEditField = uieditfield(app.AcoustoOpticsPanel, 'numeric');
            app.FreqEnviromentEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEnviromentAOEditFieldValueChanged, true);
            app.FreqEnviromentEditField.Value = 250;
            app.KHzLabel_5 = uilabel(app.AcoustoOpticsPanel);
            app.KHzLabel_5.Text = 'KHz';
            app.mmLabel_16 = uilabel(app.AcoustoOpticsPanel);
            app.mmLabel_16.Text = '00.00[mm]';
            app.dispFullFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.dispFullFFTCheckBox.Text = 'disp Full FFT';
            app.QuantAvgFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.QuantAvgFFTCheckBox.Text = 'Quant Avg. FFT';
            app.QuantAvgFFTCheckBox.Value = true;
            app.StopButton = uibutton(app.PlottingTab, 'push');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop!';
            app.PopoutFiguresPanel = uipanel(app.PlottingTab);
            app.PopoutFiguresPanel.Title = 'Pop out Figures';
            app.PopButton = uibutton(app.PopoutFiguresPanel, 'push');
            app.PopButton.Enable = 'off';
            app.PopButton.Text = 'Pop!';
            app.DropDown = uidropdown(app.PopoutFiguresPanel);
            app.DropDown.Items = {'Acousto Optics', 'Consistency', 'Scan 2D'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Acousto Optics';
            app.ExternalFiguresCheckBox = uicheckbox(app.PopoutFiguresPanel);
            app.ExternalFiguresCheckBox.Text = 'External Figures';
            app.TimeTablePanel = uipanel(app.PlottingTab);
            app.TimeTablePanel.Title = 'Time Table';
            app.DisplayTimeTableCheckBox = uicheckbox(app.TimeTablePanel);
            app.DisplayTimeTableCheckBox.Text = 'Display Time Table';
            app.DisplayTimeTableCheckBox.Value = true;
            app.Scan2DFiguresPanel = uipanel(app.PlottingTab);
            app.Scan2DFiguresPanel.Title = 'Scan 2D Figures';
            app.NavPlainDropDownLabel = uilabel(app.Scan2DFiguresPanel);
            app.NavPlainDropDownLabel.Text = 'Nav Plain';
            app.S2dNavPlainDropDown = uidropdown(app.Scan2DFiguresPanel);
            app.S2dNavPlainDropDown.Items = {'X', 'Y', 'Z'};
            app.S2dNavPlainDropDown.ValueChangedFcn = createCallbackFcn(app, @S2dNavPlainDropDownValueChanged, true);
            app.S2dNavPlainDropDown.Enable = 'off';
            app.S2dNavPlainDropDown.Value = 'Z';
            app.ReapeatSpinnerLabel = uilabel(app.Scan2DFiguresPanel);
            app.ReapeatSpinnerLabel.Text = 'Reapeat';
            app.S2dRepeatSpinner = uispinner(app.Scan2DFiguresPanel);
            app.S2dRepeatSpinner.Limits = [1 Inf];
            app.S2dRepeatSpinner.ValueChangedFcn = createCallbackFcn(app, @S2dRepeatSpinnerValueChanged, true);
            app.S2dRepeatSpinner.Enable = 'off';
            app.S2dRepeatSpinner.Value = 1;
            app.DepthCoorMainAxisLabel_2 = uilabel(app.Scan2DFiguresPanel);
            app.DepthCoorMainAxisLabel_2.Text = {'Depth Coor'; '(Main Axis)'};
            app.S2dCurDepthCoorSpinner = uispinner(app.Scan2DFiguresPanel);
            app.S2dCurDepthCoorSpinner.Limits = [0 1];
            app.S2dCurDepthCoorSpinner.ValueChangedFcn = createCallbackFcn(app, @S2dCurDepthCoorSpinnerValueChanged, true);
            app.S2dCurDepthCoorSpinner.Enable = 'off';
            app.S2dCurDepthCoorSpinner.Value = 1;
            app.NavIdxSpinnerLabel = uilabel(app.Scan2DFiguresPanel);
            app.NavIdxSpinnerLabel.Text = 'Nav Idx';
            app.S2dNavIdxSpinner = uispinner(app.Scan2DFiguresPanel);
            app.S2dNavIdxSpinner.LowerLimitInclusive = 'off';
            app.S2dNavIdxSpinner.Limits = [0 Inf];
            app.S2dNavIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @S2dNavIdxSpinnerValueChanged, true);
            app.S2dNavIdxSpinner.Enable = 'off';
            app.S2dNavIdxSpinner.Value = 1;
            app.mmLabel_14 = uilabel(app.Scan2DFiguresPanel);
            app.mmLabel_14.Text = '00.00[mm]';
            app.mmLabel_19 = uilabel(app.Scan2DFiguresPanel);
            app.mmLabel_19.Text = '00.00[mm]';
            app.NormalizeColorstoDisplayedPlaneCheckBox = uicheckbox(app.Scan2DFiguresPanel);
            app.NormalizeColorstoDisplayedPlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeColorstoDisplayedPlaneCheckBoxValueChanged, true);
            app.NormalizeColorstoDisplayedPlaneCheckBox.Text = 'Normalize Colors to Displayed Plane';
            app.acoustoOpticsPlotsTab = uitabgroup(app.UIFigure);
            app.TimeTable = uitab(app.acoustoOpticsPlotsTab);
            app.TimeTable.Title = 'Time Table';
            app.UITable2 = uitable(app.TimeTable);
            app.UITable2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2.RowName = {};
            app.UITable2_2 = uitable(app.TimeTable);
            app.UITable2_2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_2.RowName = {};
            app.UITable2_3 = uitable(app.TimeTable);
            app.UITable2_3.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_3.RowName = {};
            app.FFTTab = uitab(app.acoustoOpticsPlotsTab);
            app.FFTTab.Title = 'FFT';
            app.axFFTsm = uiaxes(app.FFTTab);
            title(app.axFFTsm, 'Title')
            xlabel(app.axFFTsm, 'X')
            ylabel(app.axFFTsm, 'Y')
            app.AvgFFTTab = uitab(app.acoustoOpticsPlotsTab);
            app.AvgFFTTab.Title = 'Avg FFT';
            app.axQAvgFFT = uiaxes(app.AvgFFTTab);
            title(app.axQAvgFFT, 'Title')
            xlabel(app.axQAvgFFT, 'X')
            ylabel(app.axQAvgFFT, 'Y')
            app.PhiTab = uitab(app.acoustoOpticsPlotsTab);
            app.PhiTab.Title = 'Phi';
            app.axPhi = uiaxes(app.PhiTab);
            title(app.axPhi, 'Title')
            xlabel(app.axPhi, 'X')
            ylabel(app.axPhi, 'Y')
            app.PhichTab = uitab(app.acoustoOpticsPlotsTab);
            app.PhichTab.Title = 'Phi (ch)';
            app.axPhiCh = uiaxes(app.PhichTab);
            title(app.axPhiCh, 'Title')
            xlabel(app.axPhiCh, 'X')
            ylabel(app.axPhiCh, 'Y')
            app.USTab = uitab(app.acoustoOpticsPlotsTab);
            app.USTab.Title = 'US';
            app.axUsSignal = uiaxes(app.USTab);
            title(app.axUsSignal, 'Title')
            xlabel(app.axUsSignal, 'X')
            ylabel(app.axUsSignal, 'Y')
            app.ReshapedTab = uitab(app.acoustoOpticsPlotsTab);
            app.ReshapedTab.Title = 'Reshaped';
            app.axReshape = uiaxes(app.ReshapedTab);
            title(app.axReshape, 'Title')
            xlabel(app.axReshape, 'X')
            ylabel(app.axReshape, 'Y')
            app.NetTab = uitab(app.acoustoOpticsPlotsTab);
            app.NetTab.Title = 'Net';
            app.axNetSignal = uiaxes(app.NetTab);
            title(app.axNetSignal, 'Title')
            xlabel(app.axNetSignal, 'X')
            ylabel(app.axNetSignal, 'Y')
            app.DeMulTab = uitab(app.acoustoOpticsPlotsTab);
            app.DeMulTab.Title = 'DeMul';
            app.axDeMul = uiaxes(app.DeMulTab);
            title(app.axDeMul, 'Title')
            xlabel(app.axDeMul, 'X')
            ylabel(app.axDeMul, 'Y')
            app.MeasSigTab = uitab(app.acoustoOpticsPlotsTab);
            app.MeasSigTab.Title = 'Meas. Sig.';
            app.axMeasSig = uiaxes(app.MeasSigTab);
            title(app.axMeasSig, 'Title')
            xlabel(app.axMeasSig, 'X')
            ylabel(app.axMeasSig, 'Y')
            app.FullSigTab = uitab(app.acoustoOpticsPlotsTab);
            app.FullSigTab.Title = 'Full Sig.';
            app.axFullSignal = uiaxes(app.FullSigTab);
            title(app.axFullSignal, 'Title')
            xlabel(app.axFullSignal, 'X')
            ylabel(app.axFullSignal, 'Y')
            app.ExtClkTab = uitab(app.acoustoOpticsPlotsTab);
            app.ExtClkTab.Title = 'Ext. Clk';
            app.axExtClk = uiaxes(app.ExtClkTab);
            title(app.axExtClk, 'Title')
            xlabel(app.axExtClk, 'X')
            ylabel(app.axExtClk, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end